classdef SPOUT2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Image                           matlab.ui.control.Image
        OptogeneticsparametersPanel     matlab.ui.container.Panel
        MultiStimStartCuepenaltyCheckBox  matlab.ui.control.CheckBox
        MultiStimDelaypenaltyCheckBox   matlab.ui.control.CheckBox
        OptoAtDelayCheckBox             matlab.ui.control.CheckBox
        OptoAtStartCueCheckBox          matlab.ui.control.CheckBox
        AdddistractionlightCheckBox     matlab.ui.control.CheckBox
        TaperstimulationoffmsEditField  matlab.ui.control.NumericEditField
        TaperstimulationoffmsEditFieldLabel  matlab.ui.control.Label
        onOptoMod                       matlab.ui.control.Lamp
        offOptoMod                      matlab.ui.control.Lamp
        ConstantmodulationSwitch        matlab.ui.control.Switch
        ConstantmodulationSwitchLabel   matlab.ui.control.Label
        PowermodulationSpinner          matlab.ui.control.Spinner
        AOMmodulationLabel              matlab.ui.control.Label
        onOptoManual                    matlab.ui.control.Lamp
        offOptoManual                   matlab.ui.control.Lamp
        TeststimulationSwitchLabel      matlab.ui.control.Label
        TeststimulationSwitch           matlab.ui.control.Switch
        MiscellaneousparametersLabel    matlab.ui.control.Label
        StimulationfrequencyHzEditField  matlab.ui.control.NumericEditField
        StimulationfrequencyHzEditFieldLabel  matlab.ui.control.Label
        PulsedurationmsEditField        matlab.ui.control.NumericEditField
        PulsedurationmsEditFieldLabel   matlab.ui.control.Label
        StimulationdurationmsEditField  matlab.ui.control.NumericEditField
        StimulationdurationmsEditFieldLabel  matlab.ui.control.Label
        ContinuousstimulationCheckBox   matlab.ui.control.CheckBox
        PulsemodulationLabel            matlab.ui.control.Label
        MultiStimCuepenaltyCheckBox     matlab.ui.control.CheckBox
        MultiStimITIpenaltyCheckBox     matlab.ui.control.CheckBox
        MultiStimInblockCheckBox        matlab.ui.control.CheckBox
        AllowmultistimLabel             matlab.ui.control.Label
        StimulationdelaymsEditField     matlab.ui.control.NumericEditField
        StimulationdelaymsEditFieldLabel  matlab.ui.control.Label
        CorrecttrialsbeforestimulationEditField  matlab.ui.control.NumericEditField
        CorrecttrialsbeforestimulationEditFieldLabel  matlab.ui.control.Label
        TrialswithstimulationSpinner    matlab.ui.control.Spinner
        OptoprobabilityLabel            matlab.ui.control.Label
        HowtostimulateLabel             matlab.ui.control.Label
        OptoAtErrorCheckBox             matlab.ui.control.CheckBox
        OptoAtRewardCheckBox            matlab.ui.control.CheckBox
        OptoAtOutcomeCheckBox           matlab.ui.control.CheckBox
        OptoAtCueCheckBox               matlab.ui.control.CheckBox
        OptoAtITICheckBox               matlab.ui.control.CheckBox
        OptoAtBlockswitchCheckBox       matlab.ui.control.CheckBox
        StimulationduringLabel          matlab.ui.control.Label
        onOpto                          matlab.ui.control.Lamp
        offOpto                         matlab.ui.control.Lamp
        OptoLabel                       matlab.ui.control.Label
        OptogeneticstimulationSwitch    matlab.ui.control.Switch
        WhentostimulateLabel            matlab.ui.control.Label
        CurrenttrialinformationLabel    matlab.ui.control.Label
        LeftTrials2RewardLabel          matlab.ui.control.Label
        LeftTrials2Reward               matlab.ui.control.Label
        RightTrials2RewardLabel         matlab.ui.control.Label
        RightTrials2Reward              matlab.ui.control.Label
        RewardSizeLabel                 matlab.ui.control.Label
        RewadSize                       matlab.ui.control.Label
        RewardProbability               matlab.ui.control.Label
        RewadProb                       matlab.ui.control.Label
        TrialperformanceLabel           matlab.ui.control.Label
        SessioninformationLabel         matlab.ui.control.Label
        TrialInBlockLabel               matlab.ui.control.Label
        TrialInBlock                    matlab.ui.control.Label
        FreeRewLabel                    matlab.ui.control.Label
        FreeRew                         matlab.ui.control.Label
        OutOfLabel                      matlab.ui.control.Label
        TotRewLabel                     matlab.ui.control.Label
        NumBlockLabel                   matlab.ui.control.Label
        RewBlockLabel                   matlab.ui.control.Label
        NumTrialsLabel                  matlab.ui.control.Label
        slash                           matlab.ui.control.Label
        RewBlock                        matlab.ui.control.Label
        NumBlock                        matlab.ui.control.Label
        TotRew                          matlab.ui.control.Label
        NumTrials                       matlab.ui.control.Label
        ManualtriggeringPanel           matlab.ui.container.Panel
        StartCueButton                  matlab.ui.control.Button
        StartcueFreqhzLabel             matlab.ui.control.Label
        StartCuefreqEditField           matlab.ui.control.NumericEditField
        LeftRightcueparametersLabel     matlab.ui.control.Label
        StartcueparametersLabel         matlab.ui.control.Label
        onCueRew                        matlab.ui.control.Lamp
        offCueRew                       matlab.ui.control.Lamp
        onCueInst                       matlab.ui.control.Lamp
        offCueInst                      matlab.ui.control.Lamp
        ManualRewardsRightLabel         matlab.ui.control.Label
        ManualRewardsLeftLabel          matlab.ui.control.Label
        CuesinstructedSwitchLabel       matlab.ui.control.Label
        RightsolenoiddurationmsLabel    matlab.ui.control.Label
        LeftsolenoidLabel               matlab.ui.control.Label
        TriggercueandmanualwaterSwitchLabel  matlab.ui.control.Label
        RCalibrateButton                matlab.ui.control.StateButton
        LCalibrateButton                matlab.ui.control.StateButton
        LicksRightCounter               matlab.ui.control.Label
        LicksLeftCounter                matlab.ui.control.Label
        LicksResetButton                matlab.ui.control.Button
        RightWaterButton                matlab.ui.control.Button
        LeftWaterButton                 matlab.ui.control.Button
        RightCueButton                  matlab.ui.control.Button
        LeftCueButton                   matlab.ui.control.Button
        RightcueLabel                   matlab.ui.control.Label
        LeftcueLabel                    matlab.ui.control.Label
        CuefrequencyhzLabel             matlab.ui.control.Label
        LicksRightLabel                 matlab.ui.control.Label
        LicksLeftLabel                  matlab.ui.control.Label
        ManualRightRewardsLabel         matlab.ui.control.Label
        ManualLeftRewardsLabel          matlab.ui.control.Label
        TriggercueandmanualwaterSwitch  matlab.ui.control.Switch
        CuesinstructedSwitch            matlab.ui.control.Switch
        RightCalibrateEditField         matlab.ui.control.Spinner
        LeftCalibrateEditField          matlab.ui.control.Spinner
        RightCuefreqEditField           matlab.ui.control.NumericEditField
        LeftCuefreqEditField            matlab.ui.control.NumericEditField
        ExecuteexperimentPanel          matlab.ui.container.Panel
        LoadandstartsessionLabel        matlab.ui.control.Label
        AutomaticallyendsessionLabel    matlab.ui.control.Label
        ManuallyendsessionLabel         matlab.ui.control.Label
        LoadsettingsButton              matlab.ui.control.Button
        TimeoutminLabel_4               matlab.ui.control.Label
        TimeoutminLabel_3               matlab.ui.control.Label
        TimeoutminLabel_2               matlab.ui.control.Label
        ResavedataButton                matlab.ui.control.Button
        SubjectnameEditFieldLabel       matlab.ui.control.Label
        TimeoutminLabel                 matlab.ui.control.Label
        OmissionsEditFieldLabel         matlab.ui.control.Label
        ImmediatestopButton             matlab.ui.control.Button
        EndBlockButton                  matlab.ui.control.StateButton
        EndTrialButton                  matlab.ui.control.StateButton
        EndOmissionsEditField           matlab.ui.control.NumericEditField
        EndTrialsEditField              matlab.ui.control.NumericEditField
        EndTimeoutEditField             matlab.ui.control.NumericEditField
        EndRewardsEditField             matlab.ui.control.NumericEditField
        SubjectnameEditField            matlab.ui.control.EditField
        TaskStartDelayEditField         matlab.ui.control.NumericEditField
        StartexperimentButton           matlab.ui.control.Button
        TimeLabel                       matlab.ui.control.Label
        RunTimeLabel                    matlab.ui.control.Label
        BlockLabel                      matlab.ui.control.Label
        BlockEventLabel                 matlab.ui.control.Label
        EventLabel                      matlab.ui.control.Label
        EventLabel_txt                  matlab.ui.control.Label
        ExperimentalparametersPanel     matlab.ui.container.Panel
        SpoutstartLabel_4               matlab.ui.control.Label
        LickToStartDropDown             matlab.ui.control.DropDown
        onDR                            matlab.ui.control.Lamp
        offDR                           matlab.ui.control.Lamp
        DRSwitch                        matlab.ui.control.Switch
        DelayedresponseLabel            matlab.ui.control.Label
        SelectiontimemsLabel_10         matlab.ui.control.Label
        StartcuepenaltymsEditFieldLabel  matlab.ui.control.Label
        SelectiontimemsLabel_9          matlab.ui.control.Label
        DelaypenaltymsEditFieldLabel    matlab.ui.control.Label
        SpoutstartLabel_3               matlab.ui.control.Label
        SelectiontimemsLabel_8          matlab.ui.control.Label
        SpoutstartLabel_2               matlab.ui.control.Label
        SelectiontimemsLabel_5          matlab.ui.control.Label
        LLLRLabel                       matlab.ui.control.Label
        PavlovianSwitchLabel            matlab.ui.control.Label
        ABTSwitchLabel                  matlab.ui.control.Label
        MinEditField_2Label_6           matlab.ui.control.Label
        MaxEditFieldLabel_3             matlab.ui.control.Label
        MinEditField_2Label_5           matlab.ui.control.Label
        MaxEditFieldLabel_2             matlab.ui.control.Label
        SelectiontimemsLabel_3          matlab.ui.control.Label
        SelectiontimemsLabel_2          matlab.ui.control.Label
        CuepenaltymsEditFieldLabel      matlab.ui.control.Label
        SpoutstartLabel                 matlab.ui.control.Label
        RewardedtrialsSpinnerLabel      matlab.ui.control.Label
        StepEditField_2Label            matlab.ui.control.Label
        MinEditField_2Label_4           matlab.ui.control.Label
        MaxEditField_2Label_4           matlab.ui.control.Label
        StepsizeLabel                   matlab.ui.control.Label
        MinEditField_2Label_3           matlab.ui.control.Label
        MaxEditField_2Label_3           matlab.ui.control.Label
        onPavl                          matlab.ui.control.Lamp
        offPavl                         matlab.ui.control.Lamp
        on2abt                          matlab.ui.control.Lamp
        off2abt                         matlab.ui.control.Lamp
        onLLLR                          matlab.ui.control.Lamp
        offLLLR                         matlab.ui.control.Lamp
        PavlovianSwitch                 matlab.ui.control.Switch
        ABTSwitch                       matlab.ui.control.Switch
        LLLRSwitch                      matlab.ui.control.Switch
        ErrorLightDurationEditField     matlab.ui.control.NumericEditField
        MaxEditField_2Label_2           matlab.ui.control.Label
        MinEditField_2Label_2           matlab.ui.control.Label
        MaxEditField_2Label             matlab.ui.control.Label
        MaxEditFieldLabel               matlab.ui.control.Label
        FailsuntilrewardsLabel          matlab.ui.control.Label
        SelectiontimemsLabel_4          matlab.ui.control.Label
        RightStepEditField              matlab.ui.control.NumericEditField
        RightMaxEditField               matlab.ui.control.NumericEditField
        RightMinEditField               matlab.ui.control.NumericEditField
        LeftStepEditField               matlab.ui.control.NumericEditField
        LeftMaxEditField                matlab.ui.control.NumericEditField
        LeftMinEditField                matlab.ui.control.NumericEditField
        RewardedtrialsSpinner           matlab.ui.control.Spinner
        RewardDelayEditField            matlab.ui.control.NumericEditField
        SelectiontimemsLabel_7          matlab.ui.control.Label
        SelectiontimemsLabel            matlab.ui.control.Label
        NoRewOutcomeTimeEditField       matlab.ui.control.NumericEditField
        RewardOutcomeTimeEditField      matlab.ui.control.NumericEditField
        Fails2RewEditField              matlab.ui.control.NumericEditField
        SelectionTimeEditField          matlab.ui.control.NumericEditField
        StartcuepenaltymsEditField      matlab.ui.control.NumericEditField
        StartCueDurationEditField       matlab.ui.control.NumericEditField
        DelaypenaltymsEditField         matlab.ui.control.NumericEditField
        DelayDurationEditField          matlab.ui.control.NumericEditField
        CuepenaltymsEditField           matlab.ui.control.NumericEditField
        CueTimeEditField                matlab.ui.control.NumericEditField
        ENLPenaltyDurationEditField     matlab.ui.control.NumericEditField
        ITI_ENL                         matlab.ui.control.DropDown
        ITIStepEditField                matlab.ui.control.NumericEditField
        ITIMaxEditField                 matlab.ui.control.NumericEditField
        ITIMinEditField                 matlab.ui.control.NumericEditField
        BlockSwitchAfterCorrectTrials   matlab.ui.control.DropDown
        TrialStart                      matlab.ui.control.DropDown
        MaxTrialsRightEditField         matlab.ui.control.NumericEditField
        MinTrialsRightEditField         matlab.ui.control.NumericEditField
        MaxTrialsLeftEditField          matlab.ui.control.NumericEditField
        MinTrialsLeftEditField          matlab.ui.control.NumericEditField
        MaxTrialsEditField              matlab.ui.control.NumericEditField
        MinTrialsEditField              matlab.ui.control.NumericEditField
        RightblocksLabel                matlab.ui.control.Label
        LeftblocksLabel                 matlab.ui.control.Label
        RightrewardsizeLabel            matlab.ui.control.Label
        LeftrewardsizeLabel             matlab.ui.control.Label
        ITIdurationmsLabel              matlab.ui.control.Label
        TrialsperblockLabel             matlab.ui.control.Label
        MinEditField_2Label             matlab.ui.control.Label
        Graph                           matlab.ui.control.UIAxes
        ContextMenu                     matlab.ui.container.ContextMenu
        WillstopexperimentbutwillnotsavedataMenu  matlab.ui.container.Menu
        ContextMenu2                    matlab.ui.container.ContextMenu
        WillstopexperimentattheendofthecurrentblockMenu  matlab.ui.container.Menu
        ContextMenu3                    matlab.ui.container.ContextMenu
        WillstopexperimentattheendofthecurrenttrialMenu  matlab.ui.container.Menu
        ContextMenu4                    matlab.ui.container.ContextMenu
        StartexperimentMenu             matlab.ui.container.Menu
        ContextMenu5                    matlab.ui.container.ContextMenu
        MinimumnumberoftrialsineachblockMenu  matlab.ui.container.Menu
        ContextMenu6                    matlab.ui.container.ContextMenu
        MaximumnumberoftrialsperblockMenu  matlab.ui.container.Menu
        ContextMenu7                    matlab.ui.container.ContextMenu
        Menu2                           matlab.ui.container.Menu
        ContextMenu8                    matlab.ui.container.ContextMenu
        AmountoftimeanimalhastomakeaselectionbeforeanomissionMenu  matlab.ui.container.Menu
        ContextMenu9                    matlab.ui.container.ContextMenu
        Menu2_2                         matlab.ui.container.Menu
        ContextMenu10                   matlab.ui.container.ContextMenu
        MinimumITIdurationMenu          matlab.ui.container.Menu
        ContextMenu11                   matlab.ui.container.ContextMenu
        MaximumITIdurationMenu          matlab.ui.container.Menu
        ContextMenu12                   matlab.ui.container.ContextMenu
        MinimumleftrewardsizedurationsolenoidsareopenforMenu  matlab.ui.container.Menu
        ContextMenu13                   matlab.ui.container.ContextMenu
        MaximumleftrewardsizedurationsolenoidsareopenforMenu  matlab.ui.container.Menu
        ContextMenu14                   matlab.ui.container.ContextMenu
        MinimumrightrewardsizedurationsolenoidsareopenforMenu  matlab.ui.container.Menu
        ContextMenu15                   matlab.ui.container.ContextMenu
        MaximumrightrewardsizedurationsolenoidsareopenforMenu  matlab.ui.container.Menu
        ContextMenu16                   matlab.ui.container.ContextMenu
        ProbabilitythatarewardisgivenintheeventofacorrectlickMenu  matlab.ui.container.Menu
        ContextMenu17                   matlab.ui.container.ContextMenu
        WhichblocktheexperimentbeginsonMenu  matlab.ui.container.Menu
        ContextMenu18                   matlab.ui.container.ContextMenu
        NameoftheanimalUsedtonamethebehavioraldatafilesMenu  matlab.ui.container.Menu
        ContextMenu19                   matlab.ui.container.ContextMenu
        ExperimentrunningtimerMenu      matlab.ui.container.Menu
        ContextMenu20                   matlab.ui.container.ContextMenu
        ResavebehavioraldatacollectedMenu  matlab.ui.container.Menu
        ContextMenu22                   matlab.ui.container.ContextMenu
        Menu2_4                         matlab.ui.container.Menu
        ContextMenu23                   matlab.ui.container.ContextMenu
        Menu2_5                         matlab.ui.container.Menu
        ContextMenu24                   matlab.ui.container.ContextMenu
        WhateventtheexperimentiscurrentlyonMenu  matlab.ui.container.Menu
        ContextMenu25                   matlab.ui.container.ContextMenu
        WhichblocksidetheexperimentiscurrentlyonMenu  matlab.ui.container.Menu
        ContextMenu26                   matlab.ui.container.ContextMenu
        FrequencyofleftcueMenu          matlab.ui.container.Menu
        ContextMenu27                   matlab.ui.container.ContextMenu
        FrequencyofrightcueMenu         matlab.ui.container.Menu
        ContextMenu28                   matlab.ui.container.ContextMenu
        ManualleftcueMenu               matlab.ui.container.Menu
        ContextMenu29                   matlab.ui.container.ContextMenu
        ManualrightcueMenu              matlab.ui.container.Menu
        ContextMenu30                   matlab.ui.container.ContextMenu
        ManualleftrewardMenu            matlab.ui.container.Menu
        ContextMenu31                   matlab.ui.container.ContextMenu
        ManualrightrewardMenu           matlab.ui.container.Menu
        ContextMenu32                   matlab.ui.container.ContextMenu
        WhenoffwaterandcuewilloperateindependentlyMenu  matlab.ui.container.Menu
        Menu                            matlab.ui.container.Menu
        ContextMenu33                   matlab.ui.container.ContextMenu
        Resetnumberofmanualleftrewardsto0Menu  matlab.ui.container.Menu
        ContextMenu35                   matlab.ui.container.ContextMenu
        NumberofmanualleftrewardsMenu   matlab.ui.container.Menu
        ContextMenu37                   matlab.ui.container.ContextMenu
        Willcause100leftrewardsUsedtocalibrateleftrewardsizeMenu  matlab.ui.container.Menu
        ContextMenu38                   matlab.ui.container.ContextMenu
        Willcause100rightrewardsUsedtocalibraterightrewardsizeMenu  matlab.ui.container.Menu
        ContextMenu40                   matlab.ui.container.ContextMenu
        Menu2_6                         matlab.ui.container.Menu
        ContextMenu41                   matlab.ui.container.ContextMenu
        ProbabilitythatoptostimulationwilloccurMenu  matlab.ui.container.Menu
        ContextMenu42                   matlab.ui.container.ContextMenu
        NumberofcorrecttrialsnecessarybeforeoptobeginsMenu  matlab.ui.container.Menu
        ContextMenu43                   matlab.ui.container.ContextMenu
        TimebetweenoptostarttimeandwhenthelaserisactuallyonMenu  matlab.ui.container.Menu
        ContextMenu44                   matlab.ui.container.ContextMenu
        IfoptoactiveduringITIallowoptostimeverytimeITIisresetMenu  matlab.ui.container.Menu
        ContextMenu45                   matlab.ui.container.ContextMenu
        AllowmultipleoptostimulationsinablockMenu  matlab.ui.container.Menu
        ContextMenu46                   matlab.ui.container.ContextMenu
        AllowoptogeneticstimulationtohavecontinuouslaserstimulationMenu  matlab.ui.container.Menu
        ContextMenu47                   matlab.ui.container.ContextMenu
        DurationthatlaserisonforMenu    matlab.ui.container.Menu
        ContextMenu48                   matlab.ui.container.ContextMenu
        IfnoncontinuousthedurationofeachlaserpulseMenu  matlab.ui.container.Menu
        ContextMenu49                   matlab.ui.container.ContextMenu
        IfnoncontinuousfrequencyoflaserMenu  matlab.ui.container.Menu
        ContextMenu50                   matlab.ui.container.ContextMenu
        NumberofmanualrightrewardsMenu_2  matlab.ui.container.Menu
    end

    %% Properties
    properties (Access = private)

        % Objects
        tee % teensy object. created at startup using AppArduinoConnection

        % Flags
        isRunning = false; % experiment is running?
        messageReceived = false; % handshake
        timedReached = false; % max timeout has been reached
        omissionsReached = false; % max omissions has been reached
        trialsReached = false; % max trials has been reached
        rewardsReached = false; % max rewards has been reached
        stopped = false; % emergency stop has been clicked
        badData = false; % emergency save data
        experimentEnding = false; % avoid matlab sending multiple '9'

        % Graphing
        selection = 0; % selection time
        lick = 0; % lick time
        blockSwitch % block   (true = left, false = right)
        visibleTrials = 100; % number of maximum trials shown at one time
        color_dot = [0.85 0.85 0.85]; % colors for placeholder
        sz_placeholder = 45; % size placeholder
        sz_addPlots = 25; % size additional plots

        % Counters
        LRCount = 0; % counter for left rewards
        RRCount = 0; % counter for right rewards
        idleLeftCount = 0; % text for left licks
        idleRightCount = 0; % text for right licks
        omissionCount = 0; % counter for omissions

        % Behavior data
        eventsData = {}; % all events
        blockData = {}; % all block summaries
        settingsData = {}; % all settings
        syncData = {}; % all sync pulses
        extSyncData = {}; % all external sync pulses
        currentEvent = 1; % Index for eventsData
        currentSync = 1; % Index for syncData
        currentExtSync = 1; % Index for extSyncData
        currentBlock = 1; % Index for blockData

        % Other
        timeout = 0; % max number of minutes before ending
        maxOmissions = 0; % max number of consecutive omissions before ending
        maxTrials = 0; % max total trials before ending
        maxRewards = 0; % max total rewards before ending
        mouseName % animal's name 
        Timer % timer object of class timer
        currentTrial = 0; % keep track of current trial
        theSettings;  % array of UI component handles for settings
        theLabels;    % corresponding label names (index-aligned with theSettings)

    end

    %% Public Methods
    methods (Access = public)

        % Default handler - recieve messages
        function defaultHandler(app, port, ~)
            
            % actually read serial com
            msg = strtrim(readline(port));

            % if we receive a message (^), connection established
            if ~isempty(msg)
                app.messageReceived = true;
            end

        end

    end

    %% Private Methods
    methods (Access = private)

        % call to get array of UI components and their labels
        function buildSettingsMap(app)
            app.theSettings = [app.SubjectnameEditField, app.LLLRSwitch, app.ABTSwitch, app.DRSwitch, app.PavlovianSwitch, app.LickToStartDropDown, app.MinTrialsEditField, app.MaxTrialsEditField, app.MinTrialsLeftEditField, ...
                app.MaxTrialsLeftEditField, app.MinTrialsRightEditField, app.MaxTrialsRightEditField, app.CueTimeEditField, app.CuepenaltymsEditField, ...
                app.DelayDurationEditField, app.DelaypenaltymsEditField, app.StartCueDurationEditField, app.StartcuepenaltymsEditField, ...
                app.SelectionTimeEditField, app.RewardOutcomeTimeEditField, app.NoRewOutcomeTimeEditField, app.Fails2RewEditField, app.ITIMinEditField, app.ITIMaxEditField, ...
                app.ITIStepEditField, app.ITI_ENL, app.LeftMinEditField, app.LeftMaxEditField, app.LeftStepEditField, app.RightMinEditField, ...
                app.RightMaxEditField, app.RightStepEditField, app.RewardedtrialsSpinner, app.RewardDelayEditField, app.TrialStart, app.LeftCuefreqEditField, app.RightCuefreqEditField, ...
                app.StartCuefreqEditField, app.ENLPenaltyDurationEditField, app.ErrorLightDurationEditField, app.BlockSwitchAfterCorrectTrials, ...
                app.CuesinstructedSwitch, app.OptogeneticstimulationSwitch, app.OptoAtBlockswitchCheckBox, app.OptoAtITICheckBox, app.OptoAtCueCheckBox, app.OptoAtDelayCheckBox, app.OptoAtStartCueCheckBox, ...
                app.OptoAtOutcomeCheckBox, app.OptoAtRewardCheckBox, app.OptoAtErrorCheckBox, app.TrialswithstimulationSpinner, app.CorrecttrialsbeforestimulationEditField, app.StimulationdelaymsEditField, app.MultiStimITIpenaltyCheckBox, ...
                app.MultiStimCuepenaltyCheckBox, app.MultiStimDelaypenaltyCheckBox, app.MultiStimStartCuepenaltyCheckBox, app.MultiStimInblockCheckBox, app.ContinuousstimulationCheckBox, app.StimulationdurationmsEditField, app.PulsedurationmsEditField, app.StimulationfrequencyHzEditField, ...
                app.PowermodulationSpinner, app.ConstantmodulationSwitch, app.TaperstimulationoffmsEditField, ...
                app.AdddistractionlightCheckBox, app.EndOmissionsEditField, app.EndTimeoutEditField, app.EndRewardsEditField, app.EndTrialsEditField, app.TaskStartDelayEditField];
            app.theLabels = ["AnimalName", "taskLLLR", "task2ABT", "taskDR", "taskPavlovian", "PavlovianLickToStart", "MinBlock", "MaxBlock", "MinBlockLeft", ...
                "MaxBlockLeft", "MinBlockRight", "MaxBlockRight", "CueTime(ms)", "CuePenalty(ms)", ...
                "DelayDuration(ms)", "DelayPenalty(ms)", "StartCueDuration(ms)", "StartCuePenalty(ms)", ...
                "SelectionTime(ms)", "CorrectTime(ms)", "IncorrectTime(ms)", "FailsUntilReward", "MinITI(ms)", "MaxITI(ms)", ...
                "ITIStep", "ITIisENL", "MinLeft(ms)", "MaxLeft(ms)", "LeftStep", "MinRight(ms)", ...
                "MaxRight(ms)", "RightStep", "RewardProbability", "RewardDelay(ms)", "SpoutStart", "LeftCueFreq(hz)", "RightCueFreq(hz)", ...
                "StartCueFreq(hz)", "ENLPenaltyDuration", "DurationErrorLight", "BlockSwitchAfterCorrectTrials", ...
                "CuesInstructed", "Opto", "OptoDuringBlockSwitch", "OptoDuringITI", "OptoDuringCue", "OptoDuringDelay", "OptoDuringStartCue", ...
                "OptoDuringOutcome", "OptoDuringConsumption", "OptoDuringError", "OptoProbability", "CorrectTrialsBeforeOpto", "OptoDelay(ms)", "AllowMultiStimITI", ...
                "AllowMultiStimCue", "AllowMultiStimDelay", "AllowMultiStimStartCue", "AllowMultiStimBlock", "ContinuousOpto", "OptoDuration(ms)", "PulseDuration(ms)", "OptoFrequency(Hz)", ...
                "OptoModulation", "OptoModContinuous", "OptoTaperOff(ms)", ...
                "AddRandomOptoLight", "MaxOmissions", "Timeout(min)", "MaxRewards", "MaxTrials", "TaskStartDelay"];
        end

        % keep track of time
        function updateTimer(app, startTime)

            % Check if we have to stop the timer
            if ~app.isRunning
                % Stop and delete timer
                stop(app.Timer);
                delete(app.Timer);
                app.Timer = [];

                % Return app to idle state
                hold(app.Graph, 'off');
                app.StartexperimentButton.Enable = 'on';
                app.tee.setMessageHandler(@app.setupHandler);
                return;
            end

            % Compute elapsed time
            elapsedTime = toc(startTime);
            hours = floor(elapsedTime / 3600);
            minutes = floor(mod(elapsedTime, 3600) / 60);
            seconds = mod(elapsedTime, 60);

            % Format time label
            if hours > 0
                timeDisplay = sprintf('%02d:%02d:%02d', hours, minutes, floor(seconds));
            else
                timeDisplay = sprintf('%02d:%02d', minutes, floor(seconds));
            end
            app.TimeLabel.Text = timeDisplay;

            % use timer to stop the task automatically (end timer if timeout is reached)
            if app.timeout > 0 && minutes >= app.timeout
                app.timedReached = true;
            end

        end

        % End experiment
        function endExperiment(app)

            % Send stop message
            app.tee.writeString('9');
            app.EventLabel.Text = 'Experiment ended';

        end

        % Save all settings, events, block data, and sync pulses
        function saveData(app)

            % reset buttons
            app.EndTrialButton.Value = false;
            app.EndBlockButton.Value = false;
            app.experimentEnding = false;

            % force task data
            if (isempty(app.eventsData) || isempty(app.blockData)) && ~app.badData
                app.myMsgbox('No data to save', 'ERROR', 'error');
                app.EventLabel.Text = 'WARNING: no data to save';
                return;
            end

            % force animal name
            if isempty(app.mouseName)
                app.mouseName = 'noName';
                app.myMsgbox('No subject name. Add name and press re-save', 'ERROR', 'error');
                % app.EventLabel.Text = 'WARNING: re-save data';
                % return;
            end

            % notify user we are saving data
            app.EventLabel.Text = 'Saving data, please wait...';

            % set dir_save to desktop folder
            dir_save = fullfile(getenv('USERPROFILE'), 'Documents', 'Behavior_Data');
            % make folder if it doesn't exist
            if ~exist(dir_save, 'dir')
                mkdir(dir_save);
            end
            cd(dir_save);

            % create unique folder name and set new dir_save
            currentDate = char(datetime('now', 'Format', 'yyyyMMdd'));
            baseFolderName = [currentDate, '_', app.mouseName];
            folderName = baseFolderName;
            folderPath = fullfile(dir_save, folderName);
            runCount = 2;
            while exist(folderPath, 'dir')
                folderName = [baseFolderName, '_run', num2str(runCount)];
                folderPath = fullfile(dir_save, folderName);
                runCount = runCount + 1;
            end
            mkdir(folderPath);
            cd(folderPath);

            % get settings and labels map
            buildSettingsMap(app);

            % Save all settings
            for i = 1:length(app.theSettings)
                currentValue = app.theSettings(i).Value;

                if ~isnumeric(currentValue)
                    currentValue = string(currentValue);
                end

                app.settingsData{end+1, 1} = app.theLabels(i);
                app.settingsData{end, 2} = currentValue;
            end
            app.settingsData{end+1, 1} = 'DataSavedTo';
            app.settingsData{end, 2} = [folderName, ' in ', dir_save];

            % Find Arduino file
            appFilePath = mfilename('fullpath');
            [appFolder, appFileName, ~] = fileparts(appFilePath);
            inoFile = dir(fullfile(appFolder, '*', '*.ino'));

            % Save Arduino file name
            if isempty(inoFile) % no arduino file found
                message = sprintf(['Arduino code should be stored in the same folder as ', appFileName '. \nPlease add arduino file and press re-save']);
                app.myMsgbox(message, 'ERROR', 'error');
                app.settingsData{end+1, 1} = 'Arduino Code';
                app.settingsData{end, 2} = 'Not Found';
            elseif length(inoFile) > 1
                app.myMsgbox('Only 1 arduino file should be stored in this folder. Please delete one and press re-save', 'ERROR', 'error');
                app.settingsData{end+1, 1} = 'Arduino Code';
                app.settingsData{end, 2} = 'More than one file found';
            else
                inoFileName = inoFile(1).name;
                app.settingsData{end+1, 1} = 'Arduino Code';
                app.settingsData{end, 2} = inoFileName;
            end

            % Save MATLAB file name
            app.settingsData{end+1, 1} = 'MATLAB Code';
            app.settingsData{end, 2} = appFileName;

            % Make all times relative to experiment start time 
            % find the first NEW_TRIAL row to use as the experiment start time
            % startRow = find(strcmp(app.eventsData(2:end, 5), 'NEW_TRIAL'), 1) + 1;
            % startTime = app.eventsData{startRow, 6};
            startTime = app.settingsData{1, 2};

            % events
            for i = 2:size(app.eventsData, 1)
                app.eventsData{i, 6} = app.eventsData{i, 6} - startTime;
            end
            % sync pulses
            for i = 2:size(app.syncData, 1)
                app.syncData{i, 2} = app.syncData{i, 2} - startTime;
            end
            % ext sync pulses
            for i = 2:size(app.extSyncData, 1)
                app.extSyncData{i, 2} = app.extSyncData{i, 2} - startTime;
            end

            % generate file names
            eventsDataFile = [currentDate, '_', app.mouseName, '_Events.csv'];
            if app.badData
                settingsDataFile = [currentDate, '_', app.mouseName, '_BadSettings.csv'];
                blocksDataFile = [currentDate, '_', app.mouseName, '_BadBlocks.csv'];
            else
                settingsDataFile = [currentDate, '_', app.mouseName, '_Settings.csv'];
                blocksDataFile = [currentDate, '_', app.mouseName, '_Blocks.csv'];
            end
            syncDataFile = [currentDate, '_', app.mouseName, '_SyncPulses.csv'];
            extSyncDataFile = [currentDate, '_', app.mouseName, '_ExtSyncPulses.csv'];

            % get all data to save
            events = app.eventsData;        % event data (raw)
            settings = app.settingsData;    % session settings
            block = app.blockData;          % block data (average)
            sync = app.syncData;            % sync pulse (raw)
            extSync = app.extSyncData;      % ext sync pulse (raw)

            % clean block, events, and sync data matrices (remove NaNs)
            block_nan = cellfun(@(x) any(isnan(x(:))), block(:,1));
            block(block_nan,:) = [];
            events_nan = cellfun(@(x) any(isnan(x(:))), events(:,1));
            events(events_nan,:) = [];
            sync_nan = cellfun(@(x) any(isnan(x(:))), sync(:,1));
            sync(sync_nan,:) = [];
            extSync_nan = cellfun(@(x) any(isnan(x(:))), extSync(:,1));
            extSync(extSync_nan,:) = [];

            % actually save/store data
            writecell(events, eventsDataFile);
            writecell(settings, settingsDataFile);
            writecell(block, blocksDataFile);
            writecell(sync, syncDataFile);
            writecell(extSync, extSyncDataFile);

            % notify user
            if app.badData
                app.myMsgbox(['All files (excl last block update) saved to ', folderName], 'Message', 'error');
            else
                app.myMsgbox(['All files saved to ', folderName], 'Message', 'none');
            end
            app.EventLabel.Text = 'Data successfully saved';

        end


        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % ---------------- Message handlers for setup --------------------%
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        % Setup handler - update variables at start of GUI
        function setupHandler(app, port, ~)

            % establish connection and receive message
            msg = strtrim(readline(port));

            % Parse messages
            if ~isempty(msg)
                parts = strsplit(msg, '\t');
                % parts = cellstr(strsplit(msg, '\t'));

                % Handle lick events
                if length(parts) >= 5
                    switch parts{5} % {5} is the event
                        case 'LickRight'
                            app.idleRightCount = app.idleRightCount + 1;
                            app.LicksRightCounter.Text = num2str(app.idleRightCount);
                        case 'LickLeft'
                            app.idleLeftCount = app.idleLeftCount + 1;
                            app.LicksLeftCounter.Text = num2str(app.idleLeftCount);
                    end

                    % Handle settings messages - load EEPROM settings
                elseif length(parts) == 2
                    parameter = str2double(parts{2});

                    % Catch default EEPROM values
                    if parameter == 4294967295 %% || parameter == 255
                        parameter = 0;
                    end

                    % figure out what input we are reading (adjust settings in matlab)
                    switch parts{1}
                        case 'Task' % check if the teensy has the right task loaded
                            if ~strcmp(strtrim(parts{2}), 'twoSpouts task')
                                app.EventLabel.Text = 'Teensy has incorrect script';
                                uiwait(msgbox( ...
                                    sprintf('Teensy is running "%s".\nExpected: SPOUT2 task.\nPlease flash the correct script.\nSerial communication stopped.', strtrim(parts{2})), ...
                                    'Incorrect Script', 'error'));
                                configureCallback(app.tee.serialConnection, "off");  % stop all serial callbacks
                                return
                            end
                        case 'Task Start Delay'
                            app.TaskStartDelayEditField.Value = parameter;
                        case 'Max Omissions'
                            app.EndOmissionsEditField.Value = parameter;
                            app.maxOmissions = parameter;
                        case 'Max Timeout'
                            app.EndTimeoutEditField.Value = parameter;
                            app.timeout = parameter;
                        case 'Max Trials'
                            app.EndTrialsEditField.Value = parameter;
                            app.maxTrials = parameter;
                        case 'Max Rewards'
                            app.EndRewardsEditField.Value = parameter;
                            app.maxRewards = parameter;
                        case 'Task LLLR'
                            if parameter == 1
                                % block/gray out correct variables and update GUI
                                updateTaskUI(app, 'lllr');
                            end
                        case 'Task 2ABT'
                            if parameter == 1
                                % block/gray out correct variables and update GUI
                                updateTaskUI(app, '2abt');
                            end
                        case 'Task DelayedResponse'
                            if parameter == 1
                                updateTaskUI(app, 'dr');
                            end
                        case 'Task Pavlovian'
                            if parameter == 1
                                % block/gray out correct variables and update GUI
                                updateTaskUI(app, 'pavlovian');
                            end
                        case 'Pavlovian Lick To Start'
                            if parameter == 1
                                app.LickToStartDropDown.Value = 'on';
                            else
                                app.LickToStartDropDown.Value = 'off';
                            end
                        case 'Block Switch After Correct'
                            if parameter == 1
                                app.BlockSwitchAfterCorrectTrials.Value = 'on';
                            else
                                app.BlockSwitchAfterCorrectTrials.Value = 'off';
                            end
                        case 'Delay Duration'
                            app.DelayDurationEditField.Value = parameter;
                        case 'Delay Penalty'
                            app.DelaypenaltymsEditField.Value = parameter;
                        case 'Start Cue Duration'
                            app.StartCueDurationEditField.Value = parameter;
                        case 'Start Cue Penalty'
                            app.StartcuepenaltymsEditField.Value = parameter;
                        case 'Start Cue Frequency'
                            app.StartCuefreqEditField.Value = parameter;
                        case 'Min Block'
                            app.MinTrialsEditField.Value = parameter;
                        case 'Max Block'
                            app.MaxTrialsEditField.Value = parameter;
                        case 'Min Block Left'
                            app.MinTrialsLeftEditField.Value = parameter;
                        case 'Max Block Left'
                            app.MaxTrialsLeftEditField.Value = parameter;
                        case 'Min Block Right'
                            app.MinTrialsRightEditField.Value = parameter;
                        case 'Max Block Right'
                            app.MaxTrialsRightEditField.Value = parameter;
                        case 'Reaction Time'
                            app.SelectionTimeEditField.Value = parameter;
                        case 'Correct Time'
                            app.RewardOutcomeTimeEditField.Value = parameter;
                        case 'Incorrect Time'
                            app.NoRewOutcomeTimeEditField.Value = parameter;
                        case 'Cue Time'
                            app.CueTimeEditField.Value = parameter;
                        case 'Cue Penalty'
                            app.CuepenaltymsEditField.Value = parameter;
                        case 'Fails Until Reward'
                            app.Fails2RewEditField.Value = parameter;
                        case 'Error Light Duration'
                            app.ErrorLightDurationEditField.Value = parameter;
                        case 'Left Calibration'
                            app.LeftCalibrateEditField.Value = parameter;
                        case 'Right Calibration'
                            app.RightCalibrateEditField.Value = parameter;
                        case 'Min ITI'
                            app.ITIMinEditField.Value = parameter;
                        case 'Max ITI'
                            app.ITIMaxEditField.Value = parameter;
                        case 'ITI Step'
                            app.ITIStepEditField.Value = parameter;
                        case 'ENL Enabled'
                            if parameter == 0
                                app.ITI_ENL.Value = 'off';
                                app.ENLPenaltyDurationEditField.Enable = 'off';
                            elseif parameter == 1
                                app.ITI_ENL.Value = 'on';
                                app.ENLPenaltyDurationEditField.Enable = 'on';
                            end
                        case 'ENL Penalty Duration'
                            app.ENLPenaltyDurationEditField.Value = parameter;
                        case 'Min Left'
                            app.LeftMinEditField.Value = parameter;
                        case 'Max Left'
                            app.LeftMaxEditField.Value = parameter;
                        case 'Left Step'
                            app.LeftStepEditField.Value = parameter;
                        case 'Min Right'
                            app.RightMinEditField.Value = parameter;
                        case 'Max Right'
                            app.RightMaxEditField.Value = parameter;
                        case 'Right Step'
                            app.RightStepEditField.Value = parameter;
                        case 'Reward Probability'
                            app.RewardedtrialsSpinner.Value = parameter;
                        case 'Reward Delay'
                            app.RewardDelayEditField.Value = parameter;
                        case 'Spout Start'
                            if parameter == 0
                                app.TrialStart.Value = 'Random';
                            elseif parameter == 1
                                app.TrialStart.Value = 'Left';
                            elseif parameter == 2
                                app.TrialStart.Value = 'Right';
                            end
                        case 'Left Cue Frequency'
                            app.LeftCuefreqEditField.Value = parameter;
                        case 'Right Cue Frequency'
                            app.RightCuefreqEditField.Value = parameter;
                        case 'Cues Instructed'
                            if parameter == 1
                                % change background
                                app.offCueInst.Visible = 'off';
                                app.onCueInst.Visible = 'on';
                                app.CuesinstructedSwitch.Value = 'on';
                            else
                                % change background
                                app.offCueInst.Visible = 'on';
                                app.onCueInst.Visible = 'off';
                                app.CuesinstructedSwitch.Value = 'off';
                            end
                        case 'Opto Enabled'
                            if parameter == 1
                                % change background
                                app.offOpto.Visible = 'off';
                                app.onOpto.Visible = 'on';
                                app.OptogeneticstimulationSwitch.Value = 'on';
                                OptogeneticstimulationSwitchValueChanged(app, []); % gray out opto panel
                            else
                                % change background
                                app.offOpto.Visible = 'on';
                                app.onOpto.Visible = 'off';
                                app.OptogeneticstimulationSwitch.Value = 'off';
                                OptogeneticstimulationSwitchValueChanged(app, []); % gray out opto panel
                            end
                        case 'Opto Continuous'
                            app.ContinuousstimulationCheckBox.Value = setCheck(app, parameter);
                            if parameter == 1  % gray out frequency modulation
                                app.PulsedurationmsEditField.Enable = 'off';
                                app.StimulationfrequencyHzEditField.Enable = 'off';
                            end
                        case 'Opto Probability'
                            app.TrialswithstimulationSpinner.Value = parameter;
                        case 'Correct Trials Before Opto'
                            app.CorrecttrialsbeforestimulationEditField.Value = parameter;
                        case 'Opto Delay'
                            app.StimulationdelaymsEditField.Value = parameter;
                        case 'Opto Duration'
                            app.StimulationdurationmsEditField.Value = parameter;
                        case 'Pulse Duration'
                            app.PulsedurationmsEditField.Value = parameter;
                        case 'Opto Frequency'
                            app.StimulationfrequencyHzEditField.Value = parameter;
                        case 'Opto Modulation'
                            app.PowermodulationSpinner.Value = parameter;
                        case 'Opto Mod Continuously'
                            if parameter == 1
                                % change background
                                app.offOptoMod.Visible = 'off';
                                app.onOptoMod.Visible = 'on';
                                app.ConstantmodulationSwitch.Value = 'on';
                            else
                                % change background
                                app.offOptoMod.Visible = 'on';
                                app.onOptoMod.Visible = 'off';
                                app.ConstantmodulationSwitch.Value = 'off';
                            end
                        case 'Opto Taper Off'
                            app.TaperstimulationoffmsEditField.Value = parameter;
                        case 'Opto At Block Switch'
                            app.OptoAtBlockswitchCheckBox.Value = setCheck(app, parameter);
                        case 'Opto At ITI'
                            app.OptoAtITICheckBox.Value = setCheck(app, parameter);
                        case 'Opto At Cue'
                            app.OptoAtCueCheckBox.Value = setCheck(app, parameter);
                        case 'Opto At Delay'
                            app.OptoAtDelayCheckBox.Value = setCheck(app, parameter);
                        case 'Opto At StartCue'
                            app.OptoAtStartCueCheckBox.Value = setCheck(app, parameter);
                        case 'Opto At Outcome'
                            app.OptoAtOutcomeCheckBox.Value = setCheck(app, parameter);
                        case 'Opto At Consumption'
                            app.OptoAtRewardCheckBox.Value = setCheck(app, parameter);
                        case 'Opto At Error'
                            app.OptoAtErrorCheckBox.Value = setCheck(app, parameter);
                        case 'Multi-stim In ITI'
                            app.MultiStimITIpenaltyCheckBox.Value = setCheck(app, parameter);
                        case 'Multi-stim In Cue'
                            app.MultiStimCuepenaltyCheckBox.Value = setCheck(app, parameter);
                        case 'Multi-stim In Delay'
                            app.MultiStimDelaypenaltyCheckBox.Value = setCheck(app, parameter);
                        case 'Multi-stim In StartCue'
                            app.MultiStimStartCuepenaltyCheckBox.Value = setCheck(app, parameter);
                        case 'Multi-stim In Block'
                            app.MultiStimInblockCheckBox.Value = setCheck(app, parameter);
                        case 'Opto Random Light'
                            app.AdddistractionlightCheckBox.Value = setCheck(app, parameter);
                        case 'EEPROM settings'
                            % notify user that EEPROM has been loaded successfully
                            app.EventLabel.Text = 'Settings successfully loaded';
                    end
                end
            end
        end


        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % ---------------- Message handlers for data ---------------------%
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        % Data handler - receive communication from arduino/teensy during session
        function dataHandler(app, port, ~)

            % Parse messages
            msg = strtrim(readline(port));
            parts = cellstr(strsplit(msg, '\t')); % cellstr(strsplit(msg, '\t'))

            % Convert numeric values - get number of inputs
            nums = str2double(parts);   % convert to double
            isnum = ~isnan(nums);       % nan's are words
            parts(isnum) = num2cell(nums(isnum));

            % Save Block data - block summary data (Block Num + value, Block + LEFT/RIGHT, Length + value, etc)
            if length(parts) > 8
                % must be a block data, but double check to be sure
                if strcmp(parts{1}, 'Block Num') % just to be sure it's block summary data
                    % get all values (first field is name, second is value)
                    app.currentBlock = app.currentBlock + 1;
                    app.blockData(app.currentBlock, :) = parts(2:2:end);

                    % Update running average of trials-to-first-reward per side
                    blockTypes = app.blockData(2:app.currentBlock, 2);
                    t2r = cell2mat(app.blockData(2:app.currentBlock, 19));
                    leftVals = t2r(strcmp(blockTypes, 'LEFT')  & t2r > 0);
                    rightVals = t2r(strcmp(blockTypes, 'RIGHT') & t2r > 0);
                    if ~isempty(leftVals)
                        app.LeftTrials2RewardLabel.Text  = sprintf('%.0f', mean(leftVals));
                    end
                    if ~isempty(rightVals)
                        app.RightTrials2RewardLabel.Text = sprintf('%.0f', mean(rightVals));
                    end
                end

                % Save sync pulse data - length 3 are sync pulses ("Sync_on" or "Sync_off", eventTime, duration) 
                % or external sync pulses ("ExtSync_on" or "ExtSync_off", eventTime, duration) 
            elseif length(parts) == 3
                % sync pulse
                if strncmp(parts{1}, 'Sync_', 4)
                    app.currentSync = app.currentSync + 1;
                    app.syncData(app.currentSync, :) = parts;

                % ext sync pulse
                elseif strncmp(parts{1}, 'ExtSync_', 7)
                    app.currentExtSync = app.currentExtSync + 1;
                    app.extSyncData(app.currentExtSync, :) = parts;
                end

                % Handle most data - length 7 are all events (nTrial, iBlock, iTrial, iRewardCount, event, eventTime, duration)
            elseif length(parts) == 7

                % Save event data
                app.currentEvent = app.currentEvent + 1;
                app.eventsData(app.currentEvent, :) = parts;

                % Handle Events
                switch parts{5}
                    % new trial
                    case {'ITI_RIGHT', 'ITI_LEFT'}

                        % Check if experiment ends on this trial
                        if app.timedReached && ~app.experimentEnding % automatically end session: timeout
                            % animal ran out of time
                            app.experimentEnding = true; % avoid matlab sending multiple '9'
                            app.endExperiment();
                            app.settingsData{end+1, 1} = 'Stopped by';
                            app.settingsData{end, 2} = 'Timeout';
                            app.timedReached = false;
                        elseif app.omissionsReached && ~app.experimentEnding % automatically end session: omissions
                            % animal omitted too many trial consecutively
                            app.experimentEnding = true; % avoid matlab sending multiple '9'
                            app.endExperiment();
                            app.settingsData{end+1, 1} = 'Stopped by';
                            app.settingsData{end, 2} = 'Omissions';
                            app.omissionsReached = false;
                        elseif app.trialsReached && ~app.experimentEnding % automatically end session: trials
                            % animal reached max total trials
                            app.experimentEnding = true; % avoid matlab sending multiple '9'
                            app.endExperiment();
                            app.settingsData{end+1, 1} = 'Stopped by';
                            app.settingsData{end, 2} = 'Max Trials';
                            app.trialsReached = false;
                        elseif app.rewardsReached && ~app.experimentEnding % automatically end session: rewards
                            % animal reached max total rewards
                            app.experimentEnding = true; % avoid matlab sending multiple '9'
                            app.endExperiment();
                            app.settingsData{end+1, 1} = 'Stopped by';
                            app.settingsData{end, 2} = 'Max Rewards';
                            app.rewardsReached = false;
                        elseif app.EndTrialButton.Value && ~app.experimentEnding
                            % experiment stops end of trial, which is this trial
                            app.experimentEnding = true; % avoid matlab sending multiple '9'
                            app.endExperiment();
                            if ~app.EndBlockButton.Value
                                app.settingsData{end+1, 1} = 'Stopped by';
                                app.settingsData{end, 2} = 'End of Trial';
                            end
                        else

                            % get ITI info
                            app.EventLabel.Text = 'ITI';
                            if strcmp(parts{5}, 'ITI_LEFT')
                                app.blockSwitch = true;
                                app.BlockLabel.Text = 'Left';
                            elseif strcmp(parts{5}, 'ITI_RIGHT')
                                app.blockSwitch = false;
                                app.BlockLabel.Text = 'Right';
                            end

                            % update figure only if this is a new trial (not just ENL penalty)
                            app.currentTrial = parts{1}; % get current trial
                            % use total trials to stop the task automatically (end timer if timeout is reached)
                            if app.maxTrials > 0 && app.currentTrial >= app.maxTrials
                                app.trialsReached = true;
                            end
                            if str2double(app.NumTrialsLabel.Text) ~= app.currentTrial % true for new trial, false for all ENL penalties

                                % update additional plots - placeholders for free reward, cue penalty, enl penalty, opto
                                scatter(app.Graph, [ones(1,4) * parts{1}], [1 2 3 4], app.sz_placeholder, 'Marker', '.', 'MarkerEdgeColor', app.color_dot, 'MarkerFaceColor', app.color_dot); % placeholders
 
                                % plot left/right trial placeholder
                                if app.blockSwitch
                                    scatter(app.Graph, parts{1}, 5.5, 15, 'MarkerEdgeColor', [0.5 0.5 0.5]); % left
                                else
                                    scatter(app.Graph, parts{1}, 6.5, 15, 'MarkerEdgeColor', [0.5 0.5 0.5]); % right
                                end

                                % update graph xlim if needed (> 100 trials in)
                                if app.currentTrial > app.visibleTrials
                                    app.Graph.XLim = [app.currentTrial - app.visibleTrials + 1, app.currentTrial + 1];
                                % else % if you want to scroll the graph from trial 1
                                %     % app.Graph.XLim = [0, app.currentTrial + 1];
                                end

                                % update trial numbers
                                app.NumTrialsLabel.Text = num2str(parts{1});
                                app.NumBlockLabel.Text = num2str(parts{2});
                                app.TrialInBlockLabel.Text = num2str(parts{3});
                            end

                        end
                    case 'DELAY'
                        app.EventLabel.Text = 'Delay';
                    case 'SELECTION'
                        app.EventLabel.Text = 'Selection';
                        app.selection = parts{6};
                    case 'WAIT_LICK' % Pavlovian trials that require a correct lick
                        app.EventLabel.Text = 'Waiting for correct lick';

                        % show penalties
                    case 'ENL_PENALTY'
                        app.EventLabel.Text = 'ENL - Penalty';
                        % plot in figure
                        scatter(app.Graph, parts{1}, 3, app.sz_addPlots, 'o', 'filled', 'MarkerFaceColor', '#edb120'); % ENL Penalty
                    case 'CUE_PENALTY'
                        app.EventLabel.Text = 'CUE - Penalty';
                        % plot in figure
                        scatter(app.Graph, parts{1}, 2, app.sz_addPlots, 'o', 'filled', 'MarkerFaceColor', '#edb120'); % Cue Penalty
                    case 'DELAY_PENALTY'
                        app.EventLabel.Text = 'Delay - Penalty';
                        scatter(app.Graph, parts{1}, 2, app.sz_addPlots, 'o', 'filled', 'MarkerFaceColor', '#edb120'); % Delay Penalty
                    case 'STARTCUE_PENALTY'
                        app.EventLabel.Text = 'Start cue - Penalty';
                        scatter(app.Graph, parts{1}, 2, app.sz_addPlots, 'o', 'filled', 'MarkerFaceColor', '#edb120');
                    
                        % show cues
                    case 'CUE_LEFT'
                        app.EventLabel.Text = 'Cue - Left';
                    case 'CUE_RIGHT'
                        app.EventLabel.Text = 'Cue - Right';
                    case 'START_CUE'
                        app.EventLabel.Text = 'Start cue';
                    case 'MANUAL_START_CUE'
                        app.EventLabel.Text = 'Manual start cue';

                        % OPTO PLOTTING
                    case 'Opto_blockSwitch'
                        scatter(app.Graph, parts{1}, 4, app.sz_addPlots, 'diamond', 'filled', 'MarkerFaceColor', '#edb120'); 
                    case 'Opto_ITI'
                        scatter(app.Graph, parts{1}, 4, app.sz_addPlots, '^', 'filled', 'MarkerFaceColor', '#edb120');
                    case 'Opto_cue'
                        scatter(app.Graph, parts{1}, 4, app.sz_addPlots, '>', 'filled', 'MarkerFaceColor', '#edb120');
                    case 'Opto_delay'
                        scatter(app.Graph, parts{1}, 4, app.sz_addPlots, 'v', 'filled', 'MarkerFaceColor', '#edb120');
                    case 'Opto_startCue'
                        scatter(app.Graph, parts{1}, 4, app.sz_addPlots, '<', 'filled', 'MarkerFaceColor', '#edb120');
                    case 'Opto_anyOutcome'
                        scatter(app.Graph, parts{1}, 4, app.sz_addPlots, 'square', 'LineWidth', 0.01, 'MarkerFaceColor', '#edb120');
                    case 'Opto_consumption'
                        scatter(app.Graph, parts{1}, 4, app.sz_addPlots*2, 'pentagram', 'filled', 'MarkerFaceColor', '#edb120');
                    case 'Opto_error'                        
                        scatter(app.Graph, parts{1}, 4, app.sz_addPlots*2, 'hexagram', 'filled', 'MarkerFaceColor', '#edb120');
                    % case 'Opto_delay_p'
                    %     scatter(app.Graph, parts{1}, 4, app.sz_addPlots, 'pentagram', 'MarkerEdgeColor', '#edb120');
                    % case 'Opto_startCue_p'
                    %     scatter(app.Graph, parts{1}, 4, app.sz_addPlots, 'hexagram', 'MarkerEdgeColor', '#edb120');

                        % Plot licks in graph - timeline figure
                    case '#_CORRECT_RIGHT'
                        app.lick = parts{6}; % get lick latency
                        % check if it was correct AND we gave a reward
                        if strcmp(app.RewardProbability.Text, '100') % reward delivered
                            scatter(app.Graph, parts{1}, 6.5, app.getDotSize(app.lick, app.selection), 'filled', 'MarkerFaceColor', '#7bde18');
                            app.EventLabel.Text = 'Correct right - Reward';
                            app.omissionCount = 0;
                        else % no reward delivery
                            scatter(app.Graph, parts{1}, 6.5, app.getDotSize(app.lick, app.selection), 'filled', 'MarkerFaceColor', '#7bde18', 'MarkerEdgeColor', [.25 .25 .25], 'LineWidth', 1);
                            app.EventLabel.Text = 'Correct right - No reward';
                            app.omissionCount = 0;
                        end
                    case '#_CORRECT_LEFT'
                        app.lick = parts{6}; % get lick latency
                        % check if it was correct AND we gave a reward
                        if strcmp(app.RewardProbability.Text, '100') % reward delivered
                            scatter(app.Graph, parts{1}, 5.5, app.getDotSize(app.lick, app.selection), 'filled', 'MarkerFaceColor', '#7bde18');
                            app.EventLabel.Text = 'Correct left - Reward';
                            app.omissionCount = 0;
                        else % no reward delivery
                            scatter(app.Graph, parts{1}, 5.5, app.getDotSize(app.lick, app.selection), 'filled', 'MarkerFaceColor', '#7bde18', 'MarkerEdgeColor', [.25 .25 .25], 'LineWidth', 1);
                            app.EventLabel.Text = 'Correct left - No reward';
                            app.omissionCount = 0;
                        end
                    case '#_INCORRECT_LEFT'
                        app.lick = parts{6}; % get lick latency
                        scatter(app.Graph, parts{1}, 6.5, app.getDotSize(app.lick, app.selection), 'filled', 'MarkerFaceColor', '#e61a33');
                        app.EventLabel.Text = 'Incorrect left';
                        app.omissionCount = 0;
                    case '#_INCORRECT_RIGHT'
                        app.lick = parts{6}; % get lick latency
                        scatter(app.Graph, parts{1}, 5.5, app.getDotSize(app.lick, app.selection), 'filled', 'MarkerFaceColor', '#e61a33');
                        app.EventLabel.Text = 'Incorrect right';
                        app.omissionCount = 0;
                    case '#_TIMEOUT'
                        if app.blockSwitch
                            scatter(app.Graph, parts{1}, 5.5, app.getDotSize(app.lick, app.selection), 'filled', 'MarkerFaceColor', '#4682b4');
                        else
                            scatter(app.Graph, parts{1}, 6.5, app.getDotSize(app.lick, app.selection), 'filled', 'MarkerFaceColor', '#4682b4');
                        end
                        app.EventLabel.Text = 'Omission';
                        app.omissionCount = app.omissionCount + 1;
                        % use omissions to stop the task automatically
                        if app.maxOmissions > 0 && app.omissionCount >= app.maxOmissions
                            app.omissionsReached = true;
                            app.omissionCount = 0;
                        end

                        % add text to eventlabel
                    case 'NO_REWARD'
                        if strcmp(app.PavlovianSwitch.Value, 'on') % only needed for Pavlovian task
                            app.EventLabel.Text = 'Correct - No Reward';
                        end
                    case 'FREE_REWARD'
                        app.EventLabel.Text = 'Free Reward';
                        % plot in figure
                        scatter(app.Graph, parts{1}, 1, app.sz_addPlots, 'o', 'filled', 'MarkerFaceColor', '#edb120'); % free reward

                        % count licks
                    case 'LickRight'
                        app.idleRightCount = app.idleRightCount + 1;
                        app.LicksRightCounter.Text = num2str(app.idleRightCount);
                    case 'LickLeft'
                        app.idleLeftCount = app.idleLeftCount + 1;
                        app.LicksLeftCounter.Text = num2str(app.idleLeftCount);

                        % Once teensy is idle, save all data
                    case 'IDLE'
                        if ~app.stopped
                            app.isRunning = false;
                            app.saveData();
                        end
                        app.stopped = false;
                end

                % Handle Block Events - update from arduino/teensy to matlab, can be anything
            elseif length(parts) == 2 && ~isempty(parts)
                switch parts{1}
                    % new block started
                    case 'Block Stats'
                        % Check if experiment ends on this block
                        if app.EndBlockButton.Value && ~app.experimentEnding % new block started, so we must stop the task
                            app.experimentEnding = true; % avoid matlab sending multiple '9'
                            app.EndTrialButton.Value = true;
                            app.endExperiment();
                            app.settingsData{end+1, 1} = 'Stopped by';
                            app.settingsData{end, 2} = 'End of Block';
                        elseif ~app.timedReached && ~app.omissionsReached && ~app.stopped && ...
                                ~app.trialsReached && ~app.rewardsReached && ...
                                ~app.EndTrialButton.Value && ~app.EndBlockButton.Value % task shouldn't stop
                            % otherwise, reset rewards in block label
                            app.RewBlockLabel.Text = '0';
                        end

                        % Update Experimental Data
                    case 'TASK STARTED AT'
                        app.settingsData{end+1, 1} = parts{1};
                        app.settingsData{end, 2} = parts{2};
                    case 'TASK ENDED AT'
                        app.settingsData{end+1, 1} = parts{1};
                        app.settingsData{end, 2}  = parts{2};
                    case 'Out of'
                        % if ~app.timedReached && ~app.omissionsReached && ~app.stopped ...
                        % && ~app.EndTrialButton.Value && ~app.EndBlockButton.Value
                        app.OutOfLabel.Text = num2str(parts{2});
                        % end
                    case 'Reward in block'
                        app.RewBlockLabel.Text = num2str(parts{2});
                    case 'Total Rewards'
                        app.TotRewLabel.Text = num2str(parts{2});
                        % plot reward if Pavlovian task
                        if strcmp(app.PavlovianSwitch.Value, 'on')
                            if app.blockSwitch % left
                                scatter(app.Graph, app.currentTrial, 5.5, 20, 'filled', 'MarkerFaceColor', '#7bde18');
                                app.EventLabel.Text = 'Pavlovian left - Reward';
                            else % right
                                scatter(app.Graph, app.currentTrial, 6.5, 20, 'filled', 'MarkerFaceColor', '#7bde18');
                                app.EventLabel.Text = 'Pavlovian right - Reward';
                            end
                        end
                        % use rewards to stop the task automatically (end timer if timeout is reached)
                        if app.maxRewards > 0 && parts{2} >= app.maxRewards
                            app.rewardsReached = true;
                        end
                    case 'Free Rewards'
                        app.FreeRewLabel.Text = num2str(parts{2});
                    case 'Min Left'
                        app.LeftMinEditField.Value = parts{2};
                    case 'Max Left'
                        app.LeftMaxEditField.Value = parts{2};
                    case 'Left Step'
                        app.LeftStepEditField.Value = parts{2};
                    case 'Min Right'
                        app.RightMinEditField.Value = parts{2};
                    case 'Max Right'
                        app.RightMaxEditField.Value = parts{2};
                    case 'Right Step'
                        app.RightStepEditField.Value = parts{2};
                    case 'Reward prob trial'
                        app.RewardProbability.Text = num2str(parts{2}*100);
                    case 'Reward size trial'
                        app.RewardSizeLabel.Text = num2str(parts{2});
                    case 'Min Block Left'
                        app.MinTrialsLeftEditField.Value = parts{2};
                    case 'Min Block Right'
                        app.MinTrialsRightEditField.Value = parts{2};
                    case 'Max Block Left'
                        app.MaxTrialsLeftEditField.Value = parts{2};
                    case 'Max Block Right'
                        app.MaxTrialsRightEditField.Value = parts{2};
                end
            end
        end


        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % -------------------- Miscellaneous functions -------------------%
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        % find difference in time between choice lick time and cue onset (in ms) to determine marker size
        function dotSize = getDotSize(app, lickTime, selectionTime)
            diff = lickTime - selectionTime; % get reaction time
            sel = app.SelectionTimeEditField.Value; % get selection threshold

            % plot dot size as ratio between reaction time and selection threshold
            if abs(diff) <= sel * .15 % lick is fast
                dotSize = 35; % 20
            elseif abs(diff) <= sel * .3 % lick is fine
                dotSize = 55; % 40
            elseif abs(diff) <= sel * .6 % lick is slow
                dotSize = 70; % 60
            else % lick is bad or omission
                dotSize = 80; % 80
            end
        end

        % assigns true to value of 1, and assigns false to value of 0
        function check = setCheck(~, num)
            if num == 1
                check = true; % true
            else
                check = false;
            end
        end

        % formats a msgbox to have larger font size and appropriate
        function myMsgbox(~, message, title, icon)
            h = msgbox(message, title, icon);

            % Change position
            pos = get(h, 'Position');
            set(h, 'Position', [pos(1), pos(2), (pos(3) + 50), pos(4)]);

            % Change font size
            textHandles = findall(h, 'Type', 'Text');
            set(textHandles, 'FontSize', 10);
        end

        % get current value of app component and formats the proper  letter command to send to arduino
        function message = msgArduino(~, appComponent, command)

            % Concatenates command and component value
            value = appComponent.Value;
            message = [command, num2str(value)];
        end

        % update task UI depending on task
        function updateTaskUI(app, task)
            % task is one of: 'lllr', '2abt', 'pavlovian'
            % gray out variables

            switch task
                case 'lllr'
                    % gray out variables not used in LLLR
                    app.SelectionTimeEditField.Enable = 'on';  % LLLR/2ABT/DR
                    app.Fails2RewEditField.Enable = 'on';  % LLLR/2ABT/DR
                    app.NoRewOutcomeTimeEditField.Enable = 'on';  % LLLR/2ABT/DR
                    app.ErrorLightDurationEditField.Enable = 'on';  % LLLR/2ABT/DR
                    app.BlockSwitchAfterCorrectTrials.Enable = 'on';   % LLLR/DR
                    app.LickToStartDropDown.Enable = 'off'; % Pavlovian
                    app.DelayDurationEditField.Enable = 'off'; % DR
                    app.DelaypenaltymsEditField.Enable = 'off'; % DR
                    app.StartCueDurationEditField.Enable = 'off'; % DR
                    app.StartcuepenaltymsEditField.Enable = 'off'; %DR
                    app.StartCuefreqEditField.Enable = 'off'; % DR
                    app.StartCueButton.Enable = 'off'; % DR

                    % change buttons
                    app.LLLRSwitch.Value = 'on';
                    app.offLLLR.Visible = 'off';
                    app.onLLLR.Visible = 'on';
                    app.ABTSwitch.Value = 'off';
                    app.off2abt.Visible = 'on';
                    app.on2abt.Visible = 'off';
                    app.DRSwitch.Value = 'off';
                    app.offDR.Visible = 'on';
                    app.onDR.Visible = 'off';
                    app.PavlovianSwitch.Value = 'off';
                    app.offPavl.Visible = 'on';
                    app.onPavl.Visible = 'off';

                    % update opto settings
                    OptogeneticstimulationSwitchValueChanged(app, []);

                case '2abt'
                    % gray out variables not used in 2ABT
                    app.SelectionTimeEditField.Enable = 'on';  % LLLR/2ABT/DR
                    app.Fails2RewEditField.Enable = 'on';  % LLLR/2ABT/DR
                    app.NoRewOutcomeTimeEditField.Enable = 'on';  % LLLR/2ABT/DR
                    app.ErrorLightDurationEditField.Enable = 'on';  % LLLR/2ABT/DR
                    app.BlockSwitchAfterCorrectTrials.Enable = 'off';   % LLLR/DR
                    app.LickToStartDropDown.Enable = 'off'; % Pavlovian
                    app.DelayDurationEditField.Enable = 'off'; % DR
                    app.DelaypenaltymsEditField.Enable = 'off'; % DR
                    app.StartCueDurationEditField.Enable = 'off'; % DR
                    app.StartcuepenaltymsEditField.Enable = 'off'; %DR
                    app.StartCuefreqEditField.Enable = 'off'; % DR
                    app.StartCueButton.Enable = 'off'; % DR

                    % change buttons
                    app.LLLRSwitch.Value = 'off';
                    app.offLLLR.Visible = 'on';
                    app.onLLLR.Visible = 'off';
                    app.ABTSwitch.Value = 'on';
                    app.off2abt.Visible = 'off';
                    app.on2abt.Visible = 'on';
                    app.DRSwitch.Value = 'off';
                    app.offDR.Visible = 'on';
                    app.onDR.Visible = 'off';
                    app.PavlovianSwitch.Value = 'off';
                    app.offPavl.Visible = 'on';
                    app.onPavl.Visible = 'off';

                    % update opto settings
                    OptogeneticstimulationSwitchValueChanged(app, []);

                case 'dr'
                    % gray out variables not used in DR
                    app.SelectionTimeEditField.Enable = 'on';  % LLLR/2ABT/DR
                    app.Fails2RewEditField.Enable = 'on';  % LLLR/2ABT/DR
                    app.NoRewOutcomeTimeEditField.Enable = 'on';  % LLLR/2ABT/DR
                    app.ErrorLightDurationEditField.Enable = 'on';  % LLLR/2ABT/DR
                    app.BlockSwitchAfterCorrectTrials.Enable = 'on';   % LLLR/DR
                    app.LickToStartDropDown.Enable = 'off'; % Pavlovian
                    app.DelayDurationEditField.Enable = 'on'; % DR
                    app.DelaypenaltymsEditField.Enable = 'on'; % DR
                    app.StartCueDurationEditField.Enable = 'on'; % DR
                    app.StartcuepenaltymsEditField.Enable = 'on'; %DR
                    app.StartCuefreqEditField.Enable = 'on'; % DR
                    app.StartCueButton.Enable = 'on'; % DR

                    % change buttons
                    app.LLLRSwitch.Value = 'off';
                    app.offLLLR.Visible = 'on';
                    app.onLLLR.Visible = 'off';
                    app.ABTSwitch.Value = 'off';
                    app.off2abt.Visible = 'on';
                    app.on2abt.Visible = 'off';
                    app.DRSwitch.Value = 'on';
                    app.offDR.Visible = 'off';
                    app.onDR.Visible = 'on';
                    app.PavlovianSwitch.Value = 'off';
                    app.offPavl.Visible = 'on';
                    app.onPavl.Visible = 'off';

                    % update opto settings
                    OptogeneticstimulationSwitchValueChanged(app, []);

                case 'pavlovian'
                    % gray out variables not used in Pavlovian
                    app.SelectionTimeEditField.Enable = 'off';  % LLLR/2ABT/DR
                    app.Fails2RewEditField.Enable = 'off';  % LLLR/2ABT/DR
                    app.NoRewOutcomeTimeEditField.Enable = 'off';  % LLLR/2ABT/DR
                    app.ErrorLightDurationEditField.Enable = 'off';  % LLLR/2ABT/DR
                    app.BlockSwitchAfterCorrectTrials.Enable = 'off';   % LLLR/DR
                    app.LickToStartDropDown.Enable = 'on'; % Pavlovian
                    app.DelayDurationEditField.Enable = 'off'; % DR
                    app.DelaypenaltymsEditField.Enable = 'off'; % DR
                    app.StartCueDurationEditField.Enable = 'off'; % DR
                    app.StartcuepenaltymsEditField.Enable = 'off'; %DR
                    app.StartCuefreqEditField.Enable = 'off'; % DR
                    app.StartCueButton.Enable = 'off'; % DR

                    % change buttons
                    app.LLLRSwitch.Value = 'off';
                    app.offLLLR.Visible = 'on';
                    app.onLLLR.Visible = 'off';
                    app.ABTSwitch.Value = 'off';
                    app.off2abt.Visible = 'on';
                    app.on2abt.Visible = 'off';
                    app.DRSwitch.Value = 'off';
                    app.offDR.Visible = 'on';
                    app.onDR.Visible = 'off';
                    app.PavlovianSwitch.Value = 'on';
                    app.offPavl.Visible = 'off';
                    app.onPavl.Visible = 'on';

                    % update opto settings
                    OptogeneticstimulationSwitchValueChanged(app, []);
            end
            
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % startup of GUI - design and clear GUI layout, establish
            % arduino connection, receive EEPROM variables
            clc;
            warning('off', 'all');
            % app.UIFigure.Position = [785, 300, 1116, 736];

            % make sure GUI starts in center of the screen (whatever screen you use)
            % get screen size
            screenSize = get(groot, 'ScreenSize');  % [left bottom width height]
            figWidth  = app.UIFigure.Position(3);
            figHeight = app.UIFigure.Position(4);

            % center horizontally, move down vertically
            x = (screenSize(3) - figWidth) / 2;
            y = screenSize(4) - figHeight - 100;  % 100 px from top

            % clamp so it never goes off-screen
            y = max(y, 50);
            app.UIFigure.Position = [x y figWidth figHeight];

            % change background switches
            app.OptogeneticstimulationSwitch.Value = 'off';
            app.CuesinstructedSwitch.Value = 'off';
            app.TriggercueandmanualwaterSwitch.Value = 'off';
            app.TriggercueandmanualwaterSwitch.Value = 'off';
            app.TriggercueandmanualwaterSwitch.Value = 'off';
            app.offOpto.Visible = 'on';
            app.onOpto.Visible = 'off';
            app.offCueInst.Visible = 'on';
            app.onCueInst.Visible = 'off';
            app.offCueRew.Visible = 'on';
            app.onCueRew.Visible = 'off';
            app.offOptoManual.Visible = 'on';
            app.onOptoManual.Visible = 'off';
            app.offOptoMod.Visible = 'on';
            app.onOptoMod.Visible = 'off';

            % plot graph
            % % % % % app.Graph.YTick = [];
            app.Graph.YTick = [1 2 3 4 5.5 6.5];
            app.Graph.YTickLabel = {'Free r','Cue p','ENL p','Opto','Left','Right'};
            app.Graph.TickLength = [0 0];
            app.Graph.YLim = [0.5, 7.5];
            app.Graph.XLim = [0, app.visibleTrials];
            app.Graph.XLimMode = 'manual';

            % Hide opto panels upon startup (can still scroll to it)
            % app.UIFigure.Position = [785, 300, 1130, 550];

            % get settings and labels map
            buildSettingsMap(app);

            % Make teensy object
            try
                app.tee = AppArduinoConnection(app, @app.defaultHandler, 115200);
            catch
                message = sprintf('Cannot connect to Arduino \nPotentially connected through Arduino IDE \nor not connected through USB at all');
                app.myMsgbox(message, 'ERROR', 'error');
                app.EventLabel.Text = 'WARNING: not connected';
                return
            end
            % Hide until handshake
            app.UIFigure.Visible = 'off';

            % send handshake (^^), which returns the same handshake
            app.tee.writeString('^^');
            connected = false;
            while ~connected
                connected = app.messageReceived;
                pause(.01); % very short pause only to initiate first connection
            end
            app.UIFigure.Visible = 'on';

            % Put app in setup mode
            app.tee.setMessageHandler(@app.setupHandler);
            app.tee.writeString('az'); % request current parameters from arduino

            % block/gray out correct variables
            updateTaskUI(app, 'lllr');

            % notify user
            disp('Connection established, variables received, and SPOUT2 GUI started')

        end

        % Button pushed function: LoadsettingsButton
        function LoadsettingsButtonPushed(app, event)
            % user select settings file
            [file, path] = uigetfile('*Settings.csv', 'Select a Settings file');
            if isequal(file, 0)
                % notify user
                app.EventLabel.Text = 'Failed to select a file';
                return
            end

            % read settings file
            raw = readcell(fullfile(path, file));

            % check if settings end with "twoSpouts" or "teensy2spouts.ino"
            if strcmp(raw(end, 2), 'SPOUT2') || strcmp(raw(end, 2), 'twoSpouts') || strcmp(raw(end, 2), 'teensy2spouts.ino'); else
                % notify user
                app.EventLabel.Text = 'Failed to load file';
                return
            end

            % CSV is Nx2: column 1 = label names, column 2 = values
            csvLabels = raw(:, 1);
            csvValues  = raw(:, 2);

            % build settings map
            buildSettingsMap(app);

            % apply matching values to GUI
            for i = 1:length(app.theLabels)
                rowIdx = find(strcmp(csvLabels, app.theLabels(i)), 1);
                if isempty(rowIdx); continue; end   % not in CSV → leave GUI unchanged

                % get values
                val = csvValues{rowIdx};

                % CheckBoxes are saved as 'true'/'false' text — convert back to logical
                if (ischar(val) || isstring(val)) && any(strcmp(val, {'true','false'}))
                    val = strcmp(val, 'true');
                end

                % DropDowns with numeric ItemsData (e.g. RatioTypeDropDown uses '0'/'1')
                % are saved as numbers — convert back to string before assignment
                if isnumeric(val) && ~islogical(val)
                    strVal = num2str(val);
                    try
                        app.theSettings(i).Value = strVal;
                        continue;  % success — skip the try-catch below
                    catch
                        % not a numeric-string dropdown, fall through to normal try-catch
                    end
                end

                % try direct assignment first, then fallbacks for type mismatches
                try
                    app.theSettings(i).Value = val;
                catch
                    if isnumeric(val)
                        try
                            app.theSettings(i).Value = num2str(val);  % e.g. TrialStart expects '0'/'1'/'2'
                        catch
                            try
                                app.theSettings(i).Value = logical(val);  % checkboxes
                            catch
                                if val == 1
                                    app.theSettings(i).Value = 'on';
                                else
                                    app.theSettings(i).Value = 'off';
                                end
                            end
                        end
                    end
                end
            end

            % notify user
            app.EventLabel.Text = 'Settings loaded from file';

            % set handler BEFORE sending (some commands echo back immediately)
            app.tee.setMessageHandler(@app.setupHandler);

            % push ALL loaded values to Teensy
            MinTrialsEditFieldValueChanged(app, []);
            MaxTrialsEditFieldValueChanged(app, []);
            CueTimeEditFieldValueChanged(app, []);
            CuepenaltymsEditFieldValueChanged(app, []);
            SelectionTimeEditFieldValueChanged(app, []);
            RewardOutcomeTimeEditFieldValueChanged(app, []);
            NoRewOutcomeTimeEditFieldValueChanged(app, []);
            Fails2RewEditFieldValueChanged(app, []);
            ITIMinEditFieldValueChanged(app, []);
            ITIMaxEditFieldValueChanged(app, []);
            ITIStepEditFieldValueChanged(app, []);
            ITI_ENLValueChanged(app, []);
            RewardedtrialsSpinnerValueChanged(app, []);
            RewardDelayEditFieldValueChanged(app, []);
            ErrorLightDurationEditFieldValueChanged(app, []);
            CuesinstructedSwitchValueChanged(app, []);
            TrialswithstimulationSpinnerValueChanged(app, []);
            OptogeneticstimulationSwitchValueChanged(app, []);
            OptoAtBlockswitchCheckBoxValueChanged(app, []);
            OptoAtITICheckBoxValueChanged(app, []);
            OptoAtCueCheckBoxValueChanged(app, []);
            OptoAtOutcomeCheckBoxValueChanged(app, []);
            OptoAtRewardCheckBoxValueChanged(app, []);
            OptoAtErrorCheckBoxValueChanged(app, []);
            CorrecttrialsbeforestimulationEditFieldValueChanged(app, []);
            StimulationdelaymsEditFieldValueChanged(app, []);
            MultiStimITIpenaltyCheckBoxValueChanged(app, []);
            MultiStimCuepenaltyCheckBoxValueChanged(app, []);
            MultiStimInblockCheckBoxValueChanged(app, []);
            ContinuousstimulationCheckBoxValueChanged(app, []);
            StimulationdurationmsEditFieldValueChanged(app, []);
            PulsedurationmsEditFieldValueChanged(app, []);
            StimulationfrequencyHzEditFieldValueChanged(app, []);
            PowermodulationSpinnerValueChanged(app, []);
            ConstantmodulationSwitchValueChanged(app, []);
            AdddistractionlightCheckBoxValueChanged(app, []);
            EndOmissionsEditFieldValueChanged(app, []);
            EndTimeoutEditFieldValueChanged(app, []);
            EndRewardsEditFieldValueChanged(app, []);
            EndTrialsEditFieldValueChanged(app, []);
            TaskStartDelayEditFieldValueChanged(app, []);
            ENLPenaltyDurationEditFieldValueChanged(app, []);
            TaperstimulationoffmsEditFieldValueChanged(app, []);
            BlockSwitchAfterCorrectTrialsValueChanged(app, []);
            TrialStartValueChanged(app, []);
            % only in SPOUT2
            LickToStartDropDownValueChanged(app, []);
            LeftCalibrateEditFieldValueChanged(app, []);
            RightCalibrateEditFieldValueChanged(app, []);
            MinTrialsLeftEditFieldValueChanged(app, []);
            MaxTrialsLeftEditFieldValueChanged(app, []);
            MinTrialsRightEditFieldValueChanged(app, []);
            MaxTrialsRightEditFieldValueChanged(app, []);
            LeftMinEditFieldValueChanged(app, []);
            LeftMaxEditFieldValueChanged(app, []);
            LeftStepEditFieldValueChanged(app, []);
            RightMinEditFieldValueChanged(app, []);
            RightMaxEditFieldValueChanged(app, []);
            RightStepEditFieldValueChanged(app, []);
            LeftCuefreqEditFieldValueChanged(app, []);
            RightCuefreqEditFieldValueChanged(app, []);
            DelayDurationEditFieldValueChanged(app, []);
            StartCueDurationEditFieldValueChanged(app, []);
            StartCuefreqEditFieldValueChanged(app, []);
            DelaypenaltymsEditFieldValueChanged(app, []);
            StartcuepenaltymsEditFieldValueChanged(app, []);
            OptoAtDelayCheckBoxValueChanged(app, []);
            OptoAtStartCueCheckBoxValueChanged(app, []);
            MultiStimDelaypenaltyCheckBoxValueChanged(app, []);
            MultiStimStartCuepenaltyCheckBoxValueChanged(app, []);

            % update tasks only when needed
            if strcmp(app.LLLRSwitch.Value, 'on')
                LLLRSwitchValueChanged(app, []);
            elseif strcmp(app.ABTSwitch.Value, 'on')
                ABTSwitchValueChanged(app, []);
            elseif strcmp(app.DRSwitch.Value, 'on')
                DRSwitchValueChanged(app, []);
            elseif strcmp(app.PavlovianSwitch.Value, 'on')
                PavlovianSwitchValueChanged(app, []);
            end

            % clean animal name
            app.SubjectnameEditField.Value = '';
            app.mouseName = '';

            % notify user
            app.EventLabel.Text = 'Settings pushed to Teensy';

            % request full dump from Teensy as confirmation
            % setupHandler will process each line and update GUI;
            app.tee.writeString('az');

        end

        % Button pushed function: StartexperimentButton
        function StartexperimentButtonPushed(app, event)
            % clearing
            clc;
            cla(app.Graph);

            % update graph
            app.visibleTrials = 100; % number of maximum trials shown at one time
            app.Graph.XLim = [0, app.visibleTrials];

            % preallocate block, event struct, and sync matrices
            app.blockData = {};
            app.blockData(1:500, 1:19) = num2cell(NaN(500, 19));
            app.blockData(1, :) = {'nBlock', 'iSpout', 'blockLength', 'nTrials', 'nCorrect', 'nIncorrect', 'nRewards', 'nNoRewards', 'nENLPenalties', 'nOmissions', 'nFreeRewards', ...
                'nLeftLicks', 'nRightLicks', 'nWinRepeat', 'nLoseSwitch', 'nWinSwitch', 'nLoseRepeat', 'nBadTrialsPostReward', 'nTrialsToSwitch'};
            app.eventsData = {};
            app.eventsData(1:50000, 1:7) = num2cell(NaN(50000, 7));
            app.eventsData(1, :) = {'nTrial', 'iBlock', 'iTrial', 'iRewardCount', 'event', 'eventTime', 'duration'};
            app.syncData = {};
            app.syncData(1:50000, 1:3) = num2cell(NaN(50000, 3));
            app.syncData(1, :) = {'status', 'time', 'duration'};
            app.extSyncData = {};
            app.extSyncData(1:50000, 1:3) = num2cell(NaN(50000, 3));
            app.extSyncData(1, :) = {'status', 'time', 'duration'};

            % empty data arrays
            app.settingsData = {};
            app.badData = false;
            app.currentEvent = 1;
            app.currentSync = 1;
            app.currentExtSync = 1;
            app.currentBlock = 1;

            % empty values in GUI
            app.TrialInBlockLabel.Text = '0';
            app.RewBlockLabel.Text = '0';
            app.OutOfLabel.Text = '0';
            app.NumBlockLabel.Text = '0';
            app.FreeRewLabel.Text = '0';
            app.NumTrialsLabel.Text = '0';
            app.TotRewLabel.Text = '0';
            app.LicksLeftCounter.Text = '0';
            app.idleLeftCount = 0; % counter for left licks
            app.LicksRightCounter.Text = '0';
            app.idleRightCount = 0; % counter for right licks
            app.RewardProbability.Text = '0';
            app.RewardSizeLabel.Text = '0';
            app.LeftTrials2RewardLabel.Text  = '0';
            app.RightTrials2RewardLabel.Text = '0';

            % make sure to turn off opto
            app.TeststimulationSwitch.Value = 'off';
            tmp.Value = 0;
            app.tee.writeString(app.msgArduino(tmp, 'bt'));

            % Setting
            app.tee.setMessageHandler(@app.dataHandler);
            hold(app.Graph, 'on');
            app.StartexperimentButton.Enable = 'off';

            % Send start message
            app.tee.writeString('8');
            app.isRunning = true;
            app.EventLabel.Text = 'Experiment Started';

            % Configure timer
            app.Timer = timer;
            app.Timer.Period = 1;  % Update the timer every 1 second
            app.Timer.ExecutionMode = 'fixedRate';
            app.Timer.BusyMode = 'drop';

            % Start timer
            app.TimeLabel.Text = '0';
            startTime = tic;
            app.Timer.TimerFcn = @(~, ~) updateTimer(app, startTime);
            start(app.Timer);

        end

        % Button pushed function: ImmediatestopButton
        function ImmediatestopButtonPushed(app, event)
            % For emergency ends only, bad block and settings data will be saved
            app.settingsData{end+1, 1} = 'Stopped by';
            app.settingsData{end, 2} = 'Emergency Stop';

            % End experiment
            app.experimentEnding = true; % avoid matlab sending multiple '9'
            app.stopped = true;
            app.endExperiment();
            app.isRunning = false;

            % Save data eventhough we did not have time to store the last block (bad data)
            app.badData = true;
            app.saveData();

            % Return to idle state
            app.tee.setMessageHandler(@app.setupHandler);
        end

        % Value changed function: MinTrialsEditField
        function MinTrialsEditFieldValueChanged(app, event)
            app.tee.writeString(app.msgArduino(app.MinTrialsEditField, 'aa'));
        end

        % Value changed function: MaxTrialsEditField
        function MaxTrialsEditFieldValueChanged(app, event)
            app.tee.writeString(app.msgArduino(app.MaxTrialsEditField, 'ab'));
        end

        % Value changed function: Fails2RewEditField
        function Fails2RewEditFieldValueChanged(app, event)
            app.tee.writeString(app.msgArduino(app.Fails2RewEditField, 'ac'));
        end

        % Value changed function: SelectionTimeEditField
        function SelectionTimeEditFieldValueChanged(app, event)
            app.tee.writeString(app.msgArduino(app.SelectionTimeEditField, 'ad'));
        end

        % Button pushed function: LeftCueButton
        function LeftCueButtonPushed(app, event)
            % send teensy - left cue
            tmp.Value = 0;
            app.tee.writeString(app.msgArduino(tmp, 'ae'));
        end

        % Button pushed function: RightCueButton
        function RightCueButtonPushed(app, event)
            % send teensy - right cue
            tmp.Value = 1;
            app.tee.writeString(app.msgArduino(tmp, 'ae'));
        end

        % Button pushed function: StartCueButton
        function StartCueButtonPushed(app, event)
            % send teensy - start cue
            tmp.Value = 1;
            app.tee.writeString(app.msgArduino(tmp, 'cn'));
        end

        % Value changed function: LeftCalibrateEditField
        function LeftCalibrateEditFieldValueChanged(app, event)
            % send teensy
            app.tee.writeString(app.msgArduino(app.LeftCalibrateEditField, 'ag'));
        end

        % Value changed function: RightCalibrateEditField
        function RightCalibrateEditFieldValueChanged(app, event)
            % send teensy
            app.tee.writeString(app.msgArduino(app.RightCalibrateEditField, 'ah'));
        end

        % Button pushed function: LeftWaterButton
        function LeftWaterButtonPushed(app, event)
            % Send left reward. If switch is on, send cue as well
            value = app.TriggercueandmanualwaterSwitch.Value;
            switch value
                case 'on'
                    app.tee.writeString('1');
                    app.LRCount = app.LRCount + 1;
                    app.ManualLeftRewardsLabel.Text = num2str(app.LRCount);
                    tmp.Value = 0;
                    app.tee.writeString(app.msgArduino(tmp, 'ae'));
                case 'off'
                    app.tee.writeString('1');
                    app.LRCount = app.LRCount + 1;
                    app.ManualLeftRewardsLabel.Text = num2str(app.LRCount);
            end
        end

        % Button pushed function: RightWaterButton
        function RightWaterButtonPushed(app, event)
            % Send right reward. If switch is on, send cue as well
            value = app.TriggercueandmanualwaterSwitch.Value;
            switch value
                case 'on'
                    app.tee.writeString('2');
                    app.RRCount = app.RRCount + 1;
                    app.ManualRightRewardsLabel.Text = num2str(app.RRCount);
                    tmp.Value = 1;
                    app.tee.writeString(app.msgArduino(tmp, 'ae'));
                case 'off'
                    app.tee.writeString('2');
                    app.RRCount = app.RRCount + 1;
                    app.ManualRightRewardsLabel.Text = num2str(app.RRCount);
            end
        end

        % Value changed function: LCalibrateButton
        function LCalibrateButtonValueChanged(app, event)
            buttonOn = app.LCalibrateButton.Value; % true if button pressed

            % when button changes, deliver 100 rewards
            for i = 1:100
                if buttonOn % if user changes button during calibration, stop
                    app.tee.writeString('1');
                    pause((app.LeftCalibrateEditField.Value * 2)/1000); % keep off for as long as it was on
                    buttonOn = app.LCalibrateButton.Value; % false if button gets unpressed
                end
            end
            app.LCalibrateButton.Value = false; % turn button off
        end

        % Value changed function: RCalibrateButton
        function RCalibrateButtonValueChanged(app, event)
            buttonOn = app.RCalibrateButton.Value;

            % when button changes, deliver 100 rewards
            for i = 1:100
                if buttonOn % if user changes button during calibration, stop
                    app.tee.writeString('2');
                    pause((app.RightCalibrateEditField.Value * 2)/1000); % keep off for as long as it was on
                    buttonOn = app.RCalibrateButton.Value;
                end
            end
            app.RCalibrateButton.Value = false;
        end

        % Button pushed function: LicksResetButton
        function LicksResetButtonPushed(app, event)
            % reset count and label to 0 for left licks and rewards
            app.LRCount = 0;
            app.ManualLeftRewardsLabel.Text = num2str(app.LRCount);
            app.idleLeftCount = 0;
            app.LicksLeftCounter.Text = num2str(app.idleLeftCount);
            % reset count and label to 0 for right licks and rewards
            app.RRCount = 0;
            app.ManualRightRewardsLabel.Text = num2str(app.RRCount);
            app.idleRightCount = 0;
            app.LicksRightCounter.Text = num2str(app.idleRightCount);
        end

        % Value changed function: ITIMinEditField
        function ITIMinEditFieldValueChanged(app, event)
            % send teensy
            app.tee.writeString(app.msgArduino(app.ITIMinEditField, 'ai'));
        end

        % Value changed function: ITIMaxEditField
        function ITIMaxEditFieldValueChanged(app, event)
            % send teensy
            app.tee.writeString(app.msgArduino(app.ITIMaxEditField, 'aj'));
        end

        % Value changed function: ITIStepEditField
        function ITIStepEditFieldValueChanged(app, event)
            % send teensy
            app.tee.writeString(app.msgArduino(app.ITIStepEditField, 'ak'));
        end

        % Value changed function: OptogeneticstimulationSwitch
        function OptogeneticstimulationSwitchValueChanged(app, event)

            % update opto settings
            value = app.OptogeneticstimulationSwitch.Value;

            % check if value is on or off
            switch value

                case 'on' % turn opto ON
                    % change background
                    app.onOpto.Visible = 'on';
                    app.offOpto.Visible = 'off';

                    % write to teensy
                    tmp.Value = 1;
                    app.tee.writeString(app.msgArduino(tmp, 'al'));

                    % when to opto stimulate
                    app.OptoAtBlockswitchCheckBox.Enable = 'on';
                    app.OptoAtITICheckBox.Enable = 'on';
                    app.OptoAtCueCheckBox.Enable = 'on';
                    app.OptoAtOutcomeCheckBox.Enable = 'on';
                    app.OptoAtRewardCheckBox.Enable = 'on';
                    app.OptoAtErrorCheckBox.Enable = 'on';
                    % how to opto stimulate
                    app.TrialswithstimulationSpinner.Enable = 'on';
                    app.CorrecttrialsbeforestimulationEditField.Enable = 'on';
                    app.StimulationdelaymsEditField.Enable = 'on';
                    app.MultiStimITIpenaltyCheckBox.Enable = 'on';
                    app.MultiStimCuepenaltyCheckBox.Enable = 'on';
                    app.MultiStimInblockCheckBox.Enable = 'on';
                    % pulse modulation parameters
                    app.ContinuousstimulationCheckBox.Enable = 'on';
                    app.StimulationdurationmsEditField.Enable = 'on';
                    % misc parameters
                    app.TeststimulationSwitch.Enable = 'on';
                    app.ConstantmodulationSwitch.Enable = 'on';
                    app.AdddistractionlightCheckBox.Enable = 'on';
                    app.PowermodulationSpinner.Enable = 'on';
                    app.TaperstimulationoffmsEditField.Enable = 'on';

                    % task Delayed-response settings
                    if strcmp(app.DRSwitch.Value, 'on') % check if DR task is ON
                        app.OptoAtDelayCheckBox.Enable = 'on';
                        app.OptoAtStartCueCheckBox.Enable = 'on';
                        app.MultiStimDelaypenaltyCheckBox.Enable = 'on';
                        app.MultiStimStartCuepenaltyCheckBox.Enable = 'on';
                    else
                        app.OptoAtDelayCheckBox.Enable = 'off';
                        app.OptoAtStartCueCheckBox.Enable = 'off';
                        app.MultiStimDelaypenaltyCheckBox.Enable = 'off';
                        app.MultiStimStartCuepenaltyCheckBox.Enable = 'off';
                    end

                    % update continuous stim
                    ContinuousstimulationCheckBoxValueChanged(app, []);

                case 'off' % turn opto OFF
                    % change background
                    app.onOpto.Visible = 'off';
                    app.offOpto.Visible = 'on';

                    % write to teensy
                    tmp.Value = 0;
                    app.tee.writeString(app.msgArduino(tmp, 'al'));

                    % Turn off options if opto is off
                    app.OptoAtBlockswitchCheckBox.Value = false;
                    OptoAtBlockswitchCheckBoxValueChanged(app, []);
                    app.OptoAtITICheckBox.Value = false;
                    OptoAtITICheckBoxValueChanged(app, []);
                    app.OptoAtCueCheckBox.Value = false;
                    OptoAtCueCheckBoxValueChanged(app, []);
                    app.OptoAtDelayCheckBox.Value = false;
                    OptoAtDelayCheckBoxValueChanged(app, []);
                    app.OptoAtStartCueCheckBox.Value = false;
                    OptoAtStartCueCheckBoxValueChanged(app, []);
                    app.OptoAtOutcomeCheckBox.Value = false;
                    OptoAtOutcomeCheckBoxValueChanged(app, []);
                    app.OptoAtRewardCheckBox.Value = false;
                    OptoAtRewardCheckBoxValueChanged(app, []);
                    app.OptoAtErrorCheckBox.Value = false;
                    OptoAtErrorCheckBoxValueChanged(app, []);

                    % turn off opto testing
                    tmp.Value = 0;
                    app.tee.writeString(app.msgArduino(tmp, 'bt'));
                    app.TeststimulationSwitch.Value = 'off';
                    % change background
                    app.offOptoManual.Visible = 'on';
                    app.onOptoManual.Visible = 'off';

                    % when to opto stimulate
                    app.OptoAtBlockswitchCheckBox.Enable = 'off';
                    app.OptoAtITICheckBox.Enable = 'off';
                    app.OptoAtCueCheckBox.Enable = 'off';
                    app.OptoAtDelayCheckBox.Enable = 'off';
                    app.OptoAtStartCueCheckBox.Enable = 'off';
                    app.OptoAtOutcomeCheckBox.Enable = 'off';
                    app.OptoAtRewardCheckBox.Enable = 'off';
                    app.OptoAtErrorCheckBox.Enable = 'off';
                    % how to opto stimulate
                    app.TrialswithstimulationSpinner.Enable = 'off';
                    app.CorrecttrialsbeforestimulationEditField.Enable = 'off';
                    app.StimulationdelaymsEditField.Enable = 'off';
                    app.MultiStimITIpenaltyCheckBox.Enable = 'off';
                    app.MultiStimCuepenaltyCheckBox.Enable = 'off';
                    app.MultiStimInblockCheckBox.Enable = 'off';
                    app.MultiStimDelaypenaltyCheckBox.Enable = 'off';
                    app.MultiStimStartCuepenaltyCheckBox.Enable = 'off';
                    % pulse modulation parameters
                    app.ContinuousstimulationCheckBox.Enable = 'off';
                    app.StimulationdurationmsEditField.Enable = 'off';
                    app.PulsedurationmsEditField.Enable = 'off';
                    app.StimulationfrequencyHzEditField.Enable = 'off';
                    % misc parameters
                    app.TeststimulationSwitch.Enable = 'off';
                    app.ConstantmodulationSwitch.Enable = 'off';
                    app.AdddistractionlightCheckBox.Enable = 'off';
                    app.PowermodulationSpinner.Enable = 'off';
                    app.TaperstimulationoffmsEditField.Enable = 'off';

            end
        end

        % Value changed function: TrialStart
        function TrialStartValueChanged(app, event)
            % send teensy
            option = app.TrialStart.Value;
            switch option
                case 'Random'
                    tmp.Value = 0;
                    app.tee.writeString(app.msgArduino(tmp, 'ao'));
                case 'Left'
                    tmp.Value = 1;
                    app.tee.writeString(app.msgArduino(tmp, 'ao'));
                case 'Right'
                    tmp.Value = 2;
                    app.tee.writeString(app.msgArduino(tmp, 'ao'));
            end
        end

        % Value changed function: LeftMinEditField
        function LeftMinEditFieldValueChanged(app, event)
            % send teensy
            app.tee.writeString(app.msgArduino(app.LeftMinEditField, 'ar'));
        end

        % Value changed function: LeftMaxEditField
        function LeftMaxEditFieldValueChanged(app, event)
            % send teensy
            app.tee.writeString(app.msgArduino(app.LeftMaxEditField, 'as'));
        end

        % Value changed function: LeftStepEditField
        function LeftStepEditFieldValueChanged(app, event)
            % send teensy
            app.tee.writeString(app.msgArduino(app.LeftStepEditField, 'at'));
        end

        % Value changed function: RightMinEditField
        function RightMinEditFieldValueChanged(app, event)
            % send teensy
            app.tee.writeString(app.msgArduino(app.RightMinEditField, 'au'));
        end

        % Value changed function: RightMaxEditField
        function RightMaxEditFieldValueChanged(app, event)
            % send teensy
            app.tee.writeString(app.msgArduino(app.RightMaxEditField, 'av'));
        end

        % Value changed function: RightStepEditField
        function RightStepEditFieldValueChanged(app, event)
            % send teensy
            app.tee.writeString(app.msgArduino(app.RightStepEditField, 'aw'));
        end

        % Value changed function: RewardedtrialsSpinner
        function RewardedtrialsSpinnerValueChanged(app, event)
            % send teensy
            app.tee.writeString(app.msgArduino(app.RewardedtrialsSpinner, 'ax'));
        end

        % Value changed function: ContinuousstimulationCheckBox
        function ContinuousstimulationCheckBoxValueChanged(app, event)
            % continuous opto stimulation
            app.tee.writeString(app.msgArduino(app.ContinuousstimulationCheckBox, 'ba'));
            value_opto = app.OptogeneticstimulationSwitch.Value;
            value_cont = app.ContinuousstimulationCheckBox.Value;

            if strcmp(value_opto, 'on') && value_cont == 1
                app.PulsedurationmsEditField.Enable = 'off';
                app.StimulationfrequencyHzEditField.Enable = 'off';
            elseif strcmp(value_opto, 'on') && value_cont == 0
                app.PulsedurationmsEditField.Enable = 'on';
                app.StimulationfrequencyHzEditField.Enable = 'on';
            end
        end

        % Value changed function: TrialswithstimulationSpinner
        function TrialswithstimulationSpinnerValueChanged(app, event)
            % send teensy
            app.tee.writeString(app.msgArduino(app.TrialswithstimulationSpinner, 'bb'));
        end

        % Value changed function: CorrecttrialsbeforestimulationEditField
        function CorrecttrialsbeforestimulationEditFieldValueChanged(app, event)
            % send teensy
            app.tee.writeString(app.msgArduino(app.CorrecttrialsbeforestimulationEditField, 'bc'));
        end

        % Value changed function: StimulationdelaymsEditField
        function StimulationdelaymsEditFieldValueChanged(app, event)
            % send teensy
            app.tee.writeString(app.msgArduino(app.StimulationdelaymsEditField, 'bd'));
        end

        % Value changed function: StimulationdurationmsEditField
        function StimulationdurationmsEditFieldValueChanged(app, event)
            % send teensy
            app.tee.writeString(app.msgArduino(app.StimulationdurationmsEditField, 'be'));
        end

        % Value changed function: PulsedurationmsEditField
        function PulsedurationmsEditFieldValueChanged(app, event)
            % send teensy
            app.tee.writeString(app.msgArduino(app.PulsedurationmsEditField, 'bf'));
        end

        % Value changed function: StimulationfrequencyHzEditField
        function StimulationfrequencyHzEditFieldValueChanged(app, event)
            % send teensy opto
            app.tee.writeString(app.msgArduino(app.StimulationfrequencyHzEditField, 'bg'));
        end

        % Value changed function: OptoAtBlockswitchCheckBox
        function OptoAtBlockswitchCheckBoxValueChanged(app, event)
            % send teensy opto
            app.tee.writeString(app.msgArduino(app.OptoAtBlockswitchCheckBox, 'bh'));
        end

        % Value changed function: OptoAtITICheckBox
        function OptoAtITICheckBoxValueChanged(app, event)
            % send teensy opto
            app.tee.writeString(app.msgArduino(app.OptoAtITICheckBox, 'bi'));
        end

        % Value changed function: OptoAtCueCheckBox
        function OptoAtCueCheckBoxValueChanged(app, event)
            % send teensy opto
            app.tee.writeString(app.msgArduino(app.OptoAtCueCheckBox, 'bj'));
        end

        % Value changed function: OptoAtDelayCheckBox
        function OptoAtDelayCheckBoxValueChanged(app, event)
            % send teensy opto
            app.tee.writeString(app.msgArduino(app.OptoAtDelayCheckBox, 'ct'));
        end

        % Value changed function: OptoAtStartCueCheckBox
        function OptoAtStartCueCheckBoxValueChanged(app, event)
            % send teensy opto
            app.tee.writeString(app.msgArduino(app.OptoAtStartCueCheckBox, 'cu'));
        end

        % Value changed function: OptoAtOutcomeCheckBox
        function OptoAtOutcomeCheckBoxValueChanged(app, event)
            % send teensy opto
            app.tee.writeString(app.msgArduino(app.OptoAtOutcomeCheckBox, 'ci'));
        end

        % Value changed function: OptoAtRewardCheckBox
        function OptoAtRewardCheckBoxValueChanged(app, event)
            % send teensy opto
            app.tee.writeString(app.msgArduino(app.OptoAtRewardCheckBox, 'bk'));
        end

        % Value changed function: OptoAtErrorCheckBox
        function OptoAtErrorCheckBoxValueChanged(app, event)
            % send teensy opto
            app.tee.writeString(app.msgArduino(app.OptoAtErrorCheckBox, 'ch'));
        end

        % Value changed function: MultiStimITIpenaltyCheckBox
        function MultiStimITIpenaltyCheckBoxValueChanged(app, event)
            % send teensy opto
            app.tee.writeString(app.msgArduino(app.MultiStimITIpenaltyCheckBox, 'bl'));
        end

        % Value changed function: MultiStimCuepenaltyCheckBox
        function MultiStimCuepenaltyCheckBoxValueChanged(app, event)
            % allow multi-stim during cue penalty
            app.tee.writeString(app.msgArduino(app.MultiStimCuepenaltyCheckBox, 'bx'));
        end

        % Value changed function: MultiStimDelaypenaltyCheckBox
        function MultiStimDelaypenaltyCheckBoxValueChanged(app, event)
            % send teensy opto
            app.tee.writeString(app.msgArduino(app.MultiStimDelaypenaltyCheckBox, 'cx'));
        end

        % Value changed function: MultiStimStartCuepenaltyCheckBox
        function MultiStimStartCuepenaltyCheckBoxValueChanged(app, event)
            % send teensy opto
            app.tee.writeString(app.msgArduino(app.MultiStimStartCuepenaltyCheckBox, 'cy'));
        end

        % Value changed function: MultiStimInblockCheckBox
        function MultiStimInblockCheckBoxValueChanged(app, event)
            app.tee.writeString(app.msgArduino(app.MultiStimInblockCheckBox, 'bm'));
        end

        % Value changed function: CuepenaltymsEditField
        function CuepenaltymsEditFieldValueChanged(app, event)
            app.tee.writeString(app.msgArduino(app.CuepenaltymsEditField, 'bn'));
        end

        % Value changed function: LeftCuefreqEditField
        function LeftCuefreqEditFieldValueChanged(app, event)
            app.tee.writeString(app.msgArduino(app.LeftCuefreqEditField, 'bo'));
        end

        % Value changed function: RightCuefreqEditField
        function RightCuefreqEditFieldValueChanged(app, event)
            app.tee.writeString(app.msgArduino(app.RightCuefreqEditField, 'bp'));
        end

        % Value changed function: EndTimeoutEditField
        function EndTimeoutEditFieldValueChanged(app, event)
            
            % max duration (min) till end of session
            app.timeout = app.EndTimeoutEditField.Value;

            % tell teensy to store in EEPROM
            app.tee.writeString(app.msgArduino(app.EndTimeoutEditField, 'bw'));
        end

        % Value changed function: EndOmissionsEditField
        function EndOmissionsEditFieldValueChanged(app, event)
           
            % max number of consecutive omissions till end of session
            app.maxOmissions = app.EndOmissionsEditField.Value;

            % tell teensy to store in EEPROM
            app.tee.writeString(app.msgArduino(app.EndOmissionsEditField, 'bv'));
        end

        % Value changed function: SubjectnameEditField
        function SubjectnameEditFieldValueChanged(app, event)
            value = app.SubjectnameEditField.Value;
            app.mouseName = value;
        end

        % Button pushed function: ResavedataButton
        function ResavedataButtonPushed(app, event)
            % remove additional fields from the arduino input
            if length(app.settingsData) > 4
                for i = length(app.settingsData):-1:4
                    app.settingsData(i, :) = [];
                end
            end

            % save session data
            app.saveData();

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Only write to Arduino if a connection was successfully established
            if ~isempty(app.tee) && isvalid(app.tee) && ~isempty(app.tee.serialConnection)
                app.tee.writeString('ay'); % save all parameters to the EEPROM
                disp(['Settings stored to EEPROM'])
            end
            delete(app)
            disp(['SPOUT2 GUI successfully closed'])
        end

        % Value changed function: RewardOutcomeTimeEditField
        function RewardOutcomeTimeEditFieldValueChanged(app, event)
            app.tee.writeString(app.msgArduino(app.RewardOutcomeTimeEditField, 'bq'));
        end

        % Value changed function: CueTimeEditField
        function CueTimeEditFieldValueChanged(app, event)
            app.tee.writeString(app.msgArduino(app.CueTimeEditField, 'br'));
        end

        % Value changed function: AdddistractionlightCheckBox
        function AdddistractionlightCheckBoxValueChanged(app, event)
            app.tee.writeString(app.msgArduino(app.AdddistractionlightCheckBox, 'bs'));
        end

        % Value changed function: TeststimulationSwitch
        function TeststimulationSwitchValueChanged(app, event)

            % change on/off into sendable command
            value = app.TeststimulationSwitch.Value;

            if strcmp(value, 'on')
                % change background
                app.offOptoManual.Visible = 'off';
                app.onOptoManual.Visible = 'on';
                tmp.Value = 1;
                app.tee.writeString(app.msgArduino(tmp, 'bt'));
            elseif strcmp(value, 'off')
                % change background
                app.offOptoManual.Visible = 'on';
                app.onOptoManual.Visible = 'off';
                tmp.Value = 0;
                app.tee.writeString(app.msgArduino(tmp, 'bt'));
            end


        end

        % Value changed function: PowermodulationSpinner
        function PowermodulationSpinnerValueChanged(app, event)
            % send updated AOM percentage value
            app.tee.writeString(app.msgArduino(app.PowermodulationSpinner, 'af'));

        end

        % Value changed function: MinTrialsLeftEditField
        function MinTrialsLeftEditFieldValueChanged(app, event)
            % send updated min number of trials in left block
            app.tee.writeString(app.msgArduino(app.MinTrialsLeftEditField, 'am'));

        end

        % Value changed function: MaxTrialsLeftEditField
        function MaxTrialsLeftEditFieldValueChanged(app, event)
            % send updated max number of trials in left block
            app.tee.writeString(app.msgArduino(app.MaxTrialsLeftEditField, 'an'));

        end

        % Value changed function: MinTrialsRightEditField
        function MinTrialsRightEditFieldValueChanged(app, event)
            % send updated min number of trials in right block
            app.tee.writeString(app.msgArduino(app.MinTrialsRightEditField, 'ap'));

        end

        % Value changed function: MaxTrialsRightEditField
        function MaxTrialsRightEditFieldValueChanged(app, event)
            % send updated max number of trials in right block
            app.tee.writeString(app.msgArduino(app.MaxTrialsRightEditField, 'aq'));

        end

        % Value changed function: TaperstimulationoffmsEditField
        function TaperstimulationoffmsEditFieldValueChanged(app, event)
            % use part of opto stim to taper of light
            app.tee.writeString(app.msgArduino(app.TaperstimulationoffmsEditField, 'by'));

        end

        % Value changed function: TriggercueandmanualwaterSwitch
        function TriggercueandmanualwaterSwitchValueChanged(app, event)
            % check if we want to give a water and cue
            value = app.TriggercueandmanualwaterSwitch.Value;
            switch value
                case 'on'
                    % change background
                    app.offCueRew.Visible = 'off';
                    app.onCueRew.Visible = 'on';
                    app.LeftWaterButton.BackgroundColor = [0.00,0.85,0.74]; % manual water button
                    app.RightWaterButton.BackgroundColor = [0.00,0.85,0.74]; % manual water button
                case 'off'
                    % change background
                    app.offCueRew.Visible = 'on';
                    app.onCueRew.Visible = 'off';
                    app.LeftWaterButton.BackgroundColor = [0.00, 0.64, 0.85]; % manual water button
                    app.RightWaterButton.BackgroundColor = [0.00, 0.64, 0.85]; % manual water button
            end
            
        end

        % Value changed function: NoRewOutcomeTimeEditField
        function NoRewOutcomeTimeEditFieldValueChanged(app, event)
            app.tee.writeString(app.msgArduino(app.NoRewOutcomeTimeEditField, 'bz'));            
        end

        % Value changed function: CuesinstructedSwitch
        function CuesinstructedSwitchValueChanged(app, event)
            % use 2 separate speakers for Left and Right cue (instructed)
            value = app.CuesinstructedSwitch.Value;
            
            if strcmp(value, 'on')
                % change background
                app.offCueInst.Visible = 'off';
                app.onCueInst.Visible = 'on';
                tmp.Value = 1;
                app.tee.writeString(app.msgArduino(tmp, 'ca'));
            elseif strcmp(value, 'off')
                % change background
                app.offCueInst.Visible = 'on';
                app.onCueInst.Visible = 'off';
                tmp.Value = 0;
                app.tee.writeString(app.msgArduino(tmp, 'ca'));
            end

        end

        % Value changed function: ConstantmodulationSwitch
        function ConstantmodulationSwitchValueChanged(app, event)

            % change on/off into sendable command
            value = app.ConstantmodulationSwitch.Value;

            if strcmp(value, 'on')
                % change background
                app.offOptoMod.Visible = 'off';
                app.onOptoMod.Visible = 'on';
                tmp.Value = 1;
                app.tee.writeString(app.msgArduino(tmp, 'cb'));
            elseif strcmp(value, 'off')
                % change background
                app.offOptoMod.Visible = 'on';
                app.onOptoMod.Visible = 'off';
                tmp.Value = 0;
                app.tee.writeString(app.msgArduino(tmp, 'cb'));
            end
        end

        % Value changed function: EndRewardsEditField
        function EndRewardsEditFieldValueChanged(app, event)
            
            % max total rewards till end of session
            app.maxRewards = app.EndRewardsEditField.Value;

            % tell teensy to store in EEPROM
            app.tee.writeString(app.msgArduino(app.EndRewardsEditField, 'cd'));
        end

        % Value changed function: EndTrialsEditField
        function EndTrialsEditFieldValueChanged(app, event)

            % max total trials till end of session
            app.maxTrials = app.EndTrialsEditField.Value;

            % tell teensy to store in EEPROM
            app.tee.writeString(app.msgArduino(app.EndTrialsEditField, 'cc'));
        end

        % Value changed function: LLLRSwitch
        function LLLRSwitchValueChanged(app, event)
            % turn ON LLLR task (i.e., 2 spout decision making)
            % change on/off into sendable command
            value = app.LLLRSwitch.Value;

            if strcmp(value, 'on')
                % tell teensy
                tmp.Value = 0;
                app.tee.writeString(app.msgArduino(tmp, 'bu')); % 2ABT
                tmp.Value = 0;
                app.tee.writeString(app.msgArduino(tmp, 'co')); % DR off
                tmp.Value = 0;
                app.tee.writeString(app.msgArduino(tmp, 'ce')); % Pavl
                tmp.Value = 1;
                app.tee.writeString(app.msgArduino(tmp, 'cj')); % LLLR

                % block/gray out correct variables and update GUI
                updateTaskUI(app, 'lllr');

            elseif strcmp(value, 'off')
                % don't allow to manually turn off
                app.LLLRSwitch.Value = 'on';
            end
        end

        % Value changed function: ABTSwitch
        function ABTSwitchValueChanged(app, event)
            % turn ON two-armed bandit version of the task (i.e., fixed
            % amount of trials with markov block switch rather than fixed 
            % amount of rewards)
            % change on/off into sendable command
            value = app.ABTSwitch.Value;

            if strcmp(value, 'on')
                % tell teensy
                tmp.Value = 0;
                app.tee.writeString(app.msgArduino(tmp, 'cj')); % LLLR
                tmp.Value = 0;
                app.tee.writeString(app.msgArduino(tmp, 'co')); % DR off
                tmp.Value = 0;
                app.tee.writeString(app.msgArduino(tmp, 'ce')); % Pavl
                tmp.Value = 1;
                app.tee.writeString(app.msgArduino(tmp, 'bu')); % 2ABT

                % block/gray out correct variables and update GUI
                updateTaskUI(app, '2abt');

            elseif strcmp(value, 'off')
                % don't allow to manually turn off
                app.ABTSwitch.Value = 'on';

            end
        end

        % Value changed function: DRSwitch
        function DRSwitchValueChanged(app, event)
            % turn ON delayed-response version of the task (i.e., fixed
            % amount of trials with markov block switch rather than fixed 
            % amount of rewards)
            % change on/off into sendable command
            value = app.DRSwitch.Value;

            if strcmp(value, 'on')
                % tell teensy
                tmp.Value = 0;
                app.tee.writeString(app.msgArduino(tmp, 'cj')); % LLLR
                tmp.Value = 0;
                app.tee.writeString(app.msgArduino(tmp, 'bu')); % 2ABT
                tmp.Value = 0;
                app.tee.writeString(app.msgArduino(tmp, 'ce')); % Pavl
                tmp.Value = 1;
                app.tee.writeString(app.msgArduino(tmp, 'co')); % DR off

                % block/gray out correct variables and update GUI
                updateTaskUI(app, 'dr');

            elseif strcmp(value, 'off')
                % don't allow to manually turn off
                app.DRSwitch.Value = 'on';

            end
        end

        % Value changed function: PavlovianSwitch
        function PavlovianSwitchValueChanged(app, event)
            % turn ON Pavlovian conditioning task (i.e., ITI/ENL -> cue ->
            % reward_delay -> reward)
            % change on/off into sendable command
            value = app.PavlovianSwitch.Value;

            % write to teensy and change gui settings
            if strcmp(value, 'on')
                % tell teensy
                tmp.Value = 0;
                app.tee.writeString(app.msgArduino(tmp, 'cj')); % LLLR
                tmp.Value = 0;
                app.tee.writeString(app.msgArduino(tmp, 'bu')); % 2ABT
                tmp.Value = 0;
                app.tee.writeString(app.msgArduino(tmp, 'co')); % DR off
                tmp.Value = 1;
                app.tee.writeString(app.msgArduino(tmp, 'ce')); % Pavl

                % block/gray out correct variables and update GUI
                updateTaskUI(app, 'pavlovian');

            elseif strcmp(value, 'off')
                % don't allow to manually turn off
                app.PavlovianSwitch.Value = 'on';

            end
        end

        % Value changed function: RewardDelayEditField
        function RewardDelayEditFieldValueChanged(app, event)

            % tell teensy to store in EEPROM
            app.tee.writeString(app.msgArduino(app.RewardDelayEditField, 'cf'));
            
        end

        % Value changed function: ITI_ENL
        function ITI_ENLValueChanged(app, event)

            % turn ON or OFF ENL (enforced non-lick ITI)
            value = app.ITI_ENL.Value;
            if strcmp(value, 'on')
                tmp.Value = 1;
                app.tee.writeString(app.msgArduino(tmp, 'cg'));
                app.ENLPenaltyDurationEditField.Enable = 'on';
            elseif strcmp(value, 'off')
                tmp.Value = 0;
                app.tee.writeString(app.msgArduino(tmp, 'cg'));
                app.ENLPenaltyDurationEditField.Enable = 'off';
            end
        end

        % Value changed function: ErrorLightDurationEditField
        function ErrorLightDurationEditFieldValueChanged(app, event)
            % send to teensy
            app.tee.writeString(app.msgArduino(app.ErrorLightDurationEditField, 'ck'));            
        end

        % Value changed function: TaskStartDelayEditField
        function TaskStartDelayEditFieldValueChanged(app, event)
            % send to teensy
            app.tee.writeString(app.msgArduino(app.TaskStartDelayEditField, 'cl'));       
        end

        % Value changed function: ENLPenaltyDurationEditField
        function ENLPenaltyDurationEditFieldValueChanged(app, event)
            % send to teensy
            app.tee.writeString(app.msgArduino(app.ENLPenaltyDurationEditField, 'cm'));              
        end

        % Value changed function: BlockSwitchAfterCorrectTrials
        function BlockSwitchAfterCorrectTrialsValueChanged(app, event)
            % send to teensy
            value = app.BlockSwitchAfterCorrectTrials.Value;
            if strcmp(value, 'on')
                tmp.Value = 1;
                app.tee.writeString(app.msgArduino(tmp, 'cs'));
            elseif strcmp(value, 'off')
                tmp.Value = 0;
                app.tee.writeString(app.msgArduino(tmp, 'cs'));
            end
        end

        % Value changed function: DelayDurationEditField
        function DelayDurationEditFieldValueChanged(app, event)
            % send to teensy
            app.tee.writeString(app.msgArduino(app.DelayDurationEditField, 'cp'));
        end

        % Value changed function: DelaypenaltymsEditField
        function DelaypenaltymsEditFieldValueChanged(app, event)
            % send to teensy
            app.tee.writeString(app.msgArduino(app.DelaypenaltymsEditField, 'cv'));
        end

        % Value changed function: StartCueDurationEditField
        function StartCueDurationEditFieldValueChanged(app, event)
            % send to teensy
            app.tee.writeString(app.msgArduino(app.StartCueDurationEditField, 'cq'));
        end

        % Value changed function: StartcuepenaltymsEditField
        function StartcuepenaltymsEditFieldValueChanged(app, event)
            % send to teensy
            app.tee.writeString(app.msgArduino(app.StartcuepenaltymsEditField, 'cw'));
        end

        % Value changed function: StartCuefreqEditField
        function StartCuefreqEditFieldValueChanged(app, event)
            % send to teensy
            app.tee.writeString(app.msgArduino(app.StartCuefreqEditField, 'cr'));
        end

        % Value changed function: LickToStartDropDown
        function LickToStartDropDownValueChanged(app, event)
            % send to teensy
            value = app.LickToStartDropDown.Value;
            if strcmp(value, 'on')
                tmp.Value = 1;
                app.tee.writeString(app.msgArduino(tmp, 'cz'));
            elseif strcmp(value, 'off')
                tmp.Value = 0;
                app.tee.writeString(app.msgArduino(tmp, 'cz'));
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Graph = uiaxes(app.UIFigure);
            xlabel(app.Graph, 'Trial')
            zlabel(app.Graph, 'Z')
            app.ExperimentalparametersPanel = uipanel(app.UIFigure);
            app.ExperimentalparametersPanel.Title = 'Experimental parameters';
            app.MinEditField_2Label = uilabel(app.ExperimentalparametersPanel);
            app.MinEditField_2Label.Text = 'Min';
            app.TrialsperblockLabel = uilabel(app.ExperimentalparametersPanel);
            app.TrialsperblockLabel.Text = 'Trials per block';
            app.ITIdurationmsLabel = uilabel(app.ExperimentalparametersPanel);
            app.ITIdurationmsLabel.Text = 'ITI duration (ms)';
            app.LeftrewardsizeLabel = uilabel(app.ExperimentalparametersPanel);
            app.LeftrewardsizeLabel.Text = 'Left reward size';
            app.RightrewardsizeLabel = uilabel(app.ExperimentalparametersPanel);
            app.RightrewardsizeLabel.Text = 'Right reward size';
            app.LeftblocksLabel = uilabel(app.ExperimentalparametersPanel);
            app.LeftblocksLabel.Text = 'Left blocks';
            app.RightblocksLabel = uilabel(app.ExperimentalparametersPanel);
            app.RightblocksLabel.Text = 'Right blocks';
            app.MinTrialsEditField = uieditfield(app.ExperimentalparametersPanel, 'numeric');
            app.MinTrialsEditField.Limits = [0 Inf];
            app.MinTrialsEditField.ValueDisplayFormat = '%11.5g';
            app.MinTrialsEditField.ValueChangedFcn = createCallbackFcn(app, @MinTrialsEditFieldValueChanged, true);
            app.MaxTrialsEditField = uieditfield(app.ExperimentalparametersPanel, 'numeric');
            app.MaxTrialsEditField.Limits = [0 Inf];
            app.MaxTrialsEditField.ValueDisplayFormat = '%11.5g';
            app.MaxTrialsEditField.ValueChangedFcn = createCallbackFcn(app, @MaxTrialsEditFieldValueChanged, true);
            app.MinTrialsLeftEditField = uieditfield(app.ExperimentalparametersPanel, 'numeric');
            app.MinTrialsLeftEditField.Limits = [0 Inf];
            app.MinTrialsLeftEditField.ValueDisplayFormat = '%11.5g';
            app.MinTrialsLeftEditField.ValueChangedFcn = createCallbackFcn(app, @MinTrialsLeftEditFieldValueChanged, true);
            app.MaxTrialsLeftEditField = uieditfield(app.ExperimentalparametersPanel, 'numeric');
            app.MaxTrialsLeftEditField.Limits = [0 Inf];
            app.MaxTrialsLeftEditField.ValueDisplayFormat = '%11.5g';
            app.MaxTrialsLeftEditField.ValueChangedFcn = createCallbackFcn(app, @MaxTrialsLeftEditFieldValueChanged, true);
            app.MinTrialsRightEditField = uieditfield(app.ExperimentalparametersPanel, 'numeric');
            app.MinTrialsRightEditField.Limits = [0 Inf];
            app.MinTrialsRightEditField.ValueDisplayFormat = '%11.5g';
            app.MinTrialsRightEditField.ValueChangedFcn = createCallbackFcn(app, @MinTrialsRightEditFieldValueChanged, true);
            app.MaxTrialsRightEditField = uieditfield(app.ExperimentalparametersPanel, 'numeric');
            app.MaxTrialsRightEditField.Limits = [0 Inf];
            app.MaxTrialsRightEditField.ValueDisplayFormat = '%11.5g';
            app.MaxTrialsRightEditField.ValueChangedFcn = createCallbackFcn(app, @MaxTrialsRightEditFieldValueChanged, true);
            app.TrialStart = uidropdown(app.ExperimentalparametersPanel);
            app.TrialStart.Items = {'Random', 'Left', 'Right'};
            app.TrialStart.ValueChangedFcn = createCallbackFcn(app, @TrialStartValueChanged, true);
            app.TrialStart.Value = 'Random';
            app.BlockSwitchAfterCorrectTrials = uidropdown(app.ExperimentalparametersPanel);
            app.BlockSwitchAfterCorrectTrials.Items = {'off', 'on'};
            app.BlockSwitchAfterCorrectTrials.ValueChangedFcn = createCallbackFcn(app, @BlockSwitchAfterCorrectTrialsValueChanged, true);
            app.BlockSwitchAfterCorrectTrials.Value = 'off';
            app.ITIMinEditField = uieditfield(app.ExperimentalparametersPanel, 'numeric');
            app.ITIMinEditField.Limits = [0 Inf];
            app.ITIMinEditField.ValueDisplayFormat = '%11.5g';
            app.ITIMinEditField.ValueChangedFcn = createCallbackFcn(app, @ITIMinEditFieldValueChanged, true);
            app.ITIMaxEditField = uieditfield(app.ExperimentalparametersPanel, 'numeric');
            app.ITIMaxEditField.Limits = [0 Inf];
            app.ITIMaxEditField.ValueDisplayFormat = '%11.5g';
            app.ITIMaxEditField.ValueChangedFcn = createCallbackFcn(app, @ITIMaxEditFieldValueChanged, true);
            app.ITIStepEditField = uieditfield(app.ExperimentalparametersPanel, 'numeric');
            app.ITIStepEditField.Limits = [0 Inf];
            app.ITIStepEditField.ValueDisplayFormat = '%11.5g';
            app.ITIStepEditField.ValueChangedFcn = createCallbackFcn(app, @ITIStepEditFieldValueChanged, true);
            app.ITI_ENL = uidropdown(app.ExperimentalparametersPanel);
            app.ITI_ENL.Items = {'off', 'on'};
            app.ITI_ENL.ValueChangedFcn = createCallbackFcn(app, @ITI_ENLValueChanged, true);
            app.ITI_ENL.Value = 'off';
            app.ENLPenaltyDurationEditField = uieditfield(app.ExperimentalparametersPanel, 'numeric');
            app.ENLPenaltyDurationEditField.Limits = [0 Inf];
            app.ENLPenaltyDurationEditField.ValueDisplayFormat = '%11.5g';
            app.ENLPenaltyDurationEditField.ValueChangedFcn = createCallbackFcn(app, @ENLPenaltyDurationEditFieldValueChanged, true);
            app.CueTimeEditField = uieditfield(app.ExperimentalparametersPanel, 'numeric');
            app.CueTimeEditField.Limits = [0 Inf];
            app.CueTimeEditField.ValueDisplayFormat = '%11.5g';
            app.CueTimeEditField.ValueChangedFcn = createCallbackFcn(app, @CueTimeEditFieldValueChanged, true);
            app.CuepenaltymsEditField = uieditfield(app.ExperimentalparametersPanel, 'numeric');
            app.CuepenaltymsEditField.Limits = [0 Inf];
            app.CuepenaltymsEditField.ValueDisplayFormat = '%11.5g';
            app.CuepenaltymsEditField.ValueChangedFcn = createCallbackFcn(app, @CuepenaltymsEditFieldValueChanged, true);
            app.DelayDurationEditField = uieditfield(app.ExperimentalparametersPanel, 'numeric');
            app.DelayDurationEditField.Limits = [0 Inf];
            app.DelayDurationEditField.ValueDisplayFormat = '%11.5g';
            app.DelayDurationEditField.ValueChangedFcn = createCallbackFcn(app, @DelayDurationEditFieldValueChanged, true);
            app.DelaypenaltymsEditField = uieditfield(app.ExperimentalparametersPanel, 'numeric');
            app.DelaypenaltymsEditField.Limits = [0 Inf];
            app.DelaypenaltymsEditField.ValueDisplayFormat = '%11.5g';
            app.DelaypenaltymsEditField.ValueChangedFcn = createCallbackFcn(app, @DelaypenaltymsEditFieldValueChanged, true);
            app.StartCueDurationEditField = uieditfield(app.ExperimentalparametersPanel, 'numeric');
            app.StartCueDurationEditField.Limits = [0 Inf];
            app.StartCueDurationEditField.ValueDisplayFormat = '%11.5g';
            app.StartCueDurationEditField.ValueChangedFcn = createCallbackFcn(app, @StartCueDurationEditFieldValueChanged, true);
            app.StartcuepenaltymsEditField = uieditfield(app.ExperimentalparametersPanel, 'numeric');
            app.StartcuepenaltymsEditField.Limits = [0 Inf];
            app.StartcuepenaltymsEditField.ValueDisplayFormat = '%11.5g';
            app.StartcuepenaltymsEditField.ValueChangedFcn = createCallbackFcn(app, @StartcuepenaltymsEditFieldValueChanged, true);
            app.SelectionTimeEditField = uieditfield(app.ExperimentalparametersPanel, 'numeric');
            app.SelectionTimeEditField.Limits = [0 Inf];
            app.SelectionTimeEditField.ValueDisplayFormat = '%11.5g';
            app.SelectionTimeEditField.ValueChangedFcn = createCallbackFcn(app, @SelectionTimeEditFieldValueChanged, true);
            app.Fails2RewEditField = uieditfield(app.ExperimentalparametersPanel, 'numeric');
            app.Fails2RewEditField.Limits = [0 Inf];
            app.Fails2RewEditField.ValueDisplayFormat = '%11.5g';
            app.Fails2RewEditField.ValueChangedFcn = createCallbackFcn(app, @Fails2RewEditFieldValueChanged, true);
            app.RewardOutcomeTimeEditField = uieditfield(app.ExperimentalparametersPanel, 'numeric');
            app.RewardOutcomeTimeEditField.Limits = [0 Inf];
            app.RewardOutcomeTimeEditField.ValueDisplayFormat = '%11.5g';
            app.RewardOutcomeTimeEditField.ValueChangedFcn = createCallbackFcn(app, @RewardOutcomeTimeEditFieldValueChanged, true);
            app.NoRewOutcomeTimeEditField = uieditfield(app.ExperimentalparametersPanel, 'numeric');
            app.NoRewOutcomeTimeEditField.Limits = [0 Inf];
            app.NoRewOutcomeTimeEditField.ValueDisplayFormat = '%11.5g';
            app.NoRewOutcomeTimeEditField.ValueChangedFcn = createCallbackFcn(app, @NoRewOutcomeTimeEditFieldValueChanged, true);
            app.SelectiontimemsLabel = uilabel(app.ExperimentalparametersPanel);
            app.SelectiontimemsLabel.Text = 'Selection duration (ms)';
            app.SelectiontimemsLabel_7 = uilabel(app.ExperimentalparametersPanel);
            app.SelectiontimemsLabel_7.Text = 'Error light duration (ms)';
            app.RewardDelayEditField = uieditfield(app.ExperimentalparametersPanel, 'numeric');
            app.RewardDelayEditField.Limits = [0 Inf];
            app.RewardDelayEditField.ValueDisplayFormat = '%11.5g';
            app.RewardDelayEditField.ValueChangedFcn = createCallbackFcn(app, @RewardDelayEditFieldValueChanged, true);
            app.RewardedtrialsSpinner = uispinner(app.ExperimentalparametersPanel);
            app.RewardedtrialsSpinner.Step = 5;
            app.RewardedtrialsSpinner.Limits = [0 100];
            app.RewardedtrialsSpinner.ValueChangedFcn = createCallbackFcn(app, @RewardedtrialsSpinnerValueChanged, true);
            app.RewardedtrialsSpinner.Value = 100;
            app.LeftMinEditField = uieditfield(app.ExperimentalparametersPanel, 'numeric');
            app.LeftMinEditField.Limits = [0 Inf];
            app.LeftMinEditField.ValueDisplayFormat = '%11.5g';
            app.LeftMinEditField.ValueChangedFcn = createCallbackFcn(app, @LeftMinEditFieldValueChanged, true);
            app.LeftMaxEditField = uieditfield(app.ExperimentalparametersPanel, 'numeric');
            app.LeftMaxEditField.Limits = [0 Inf];
            app.LeftMaxEditField.ValueDisplayFormat = '%11.5g';
            app.LeftMaxEditField.ValueChangedFcn = createCallbackFcn(app, @LeftMaxEditFieldValueChanged, true);
            app.LeftStepEditField = uieditfield(app.ExperimentalparametersPanel, 'numeric');
            app.LeftStepEditField.Limits = [0 Inf];
            app.LeftStepEditField.ValueDisplayFormat = '%11.5g';
            app.LeftStepEditField.ValueChangedFcn = createCallbackFcn(app, @LeftStepEditFieldValueChanged, true);
            app.RightMinEditField = uieditfield(app.ExperimentalparametersPanel, 'numeric');
            app.RightMinEditField.Limits = [0 Inf];
            app.RightMinEditField.ValueDisplayFormat = '%11.5g';
            app.RightMinEditField.ValueChangedFcn = createCallbackFcn(app, @RightMinEditFieldValueChanged, true);
            app.RightMaxEditField = uieditfield(app.ExperimentalparametersPanel, 'numeric');
            app.RightMaxEditField.Limits = [0 Inf];
            app.RightMaxEditField.ValueDisplayFormat = '%11.5g';
            app.RightMaxEditField.ValueChangedFcn = createCallbackFcn(app, @RightMaxEditFieldValueChanged, true);
            app.RightStepEditField = uieditfield(app.ExperimentalparametersPanel, 'numeric');
            app.RightStepEditField.Limits = [0 Inf];
            app.RightStepEditField.ValueDisplayFormat = '%11.5g';
            app.RightStepEditField.ValueChangedFcn = createCallbackFcn(app, @RightStepEditFieldValueChanged, true);
            app.SelectiontimemsLabel_4 = uilabel(app.ExperimentalparametersPanel);
            app.SelectiontimemsLabel_4.Text = 'No-rew outcome (ms)';
            app.FailsuntilrewardsLabel = uilabel(app.ExperimentalparametersPanel);
            app.FailsuntilrewardsLabel.Text = 'Fails until reward';
            app.MaxEditFieldLabel = uilabel(app.ExperimentalparametersPanel);
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxEditField_2Label = uilabel(app.ExperimentalparametersPanel);
            app.MaxEditField_2Label.Text = 'Max';
            app.MinEditField_2Label_2 = uilabel(app.ExperimentalparametersPanel);
            app.MinEditField_2Label_2.Text = 'Min';
            app.MaxEditField_2Label_2 = uilabel(app.ExperimentalparametersPanel);
            app.MaxEditField_2Label_2.Text = {'Step'; 'size'};
            app.ErrorLightDurationEditField = uieditfield(app.ExperimentalparametersPanel, 'numeric');
            app.ErrorLightDurationEditField.Limits = [0 Inf];
            app.ErrorLightDurationEditField.ValueDisplayFormat = '%11.5g';
            app.ErrorLightDurationEditField.ValueChangedFcn = createCallbackFcn(app, @ErrorLightDurationEditFieldValueChanged, true);
            app.LLLRSwitch = uiswitch(app.ExperimentalparametersPanel, 'slider');
            app.LLLRSwitch.Items = {'off', 'on'};
            app.LLLRSwitch.ValueChangedFcn = createCallbackFcn(app, @LLLRSwitchValueChanged, true);
            app.LLLRSwitch.Value = 'off';
            app.ABTSwitch = uiswitch(app.ExperimentalparametersPanel, 'slider');
            app.ABTSwitch.Items = {'off', 'on'};
            app.ABTSwitch.ValueChangedFcn = createCallbackFcn(app, @ABTSwitchValueChanged, true);
            app.ABTSwitch.Value = 'off';
            app.PavlovianSwitch = uiswitch(app.ExperimentalparametersPanel, 'slider');
            app.PavlovianSwitch.Items = {'off', 'on'};
            app.PavlovianSwitch.ValueChangedFcn = createCallbackFcn(app, @PavlovianSwitchValueChanged, true);
            app.PavlovianSwitch.Value = 'off';
            app.offLLLR = uilamp(app.ExperimentalparametersPanel);
            app.onLLLR = uilamp(app.ExperimentalparametersPanel);
            app.off2abt = uilamp(app.ExperimentalparametersPanel);
            app.on2abt = uilamp(app.ExperimentalparametersPanel);
            app.offPavl = uilamp(app.ExperimentalparametersPanel);
            app.onPavl = uilamp(app.ExperimentalparametersPanel);
            app.MaxEditField_2Label_3 = uilabel(app.ExperimentalparametersPanel);
            app.MaxEditField_2Label_3.Text = 'Max';
            app.MinEditField_2Label_3 = uilabel(app.ExperimentalparametersPanel);
            app.MinEditField_2Label_3.Text = 'Min';
            app.StepsizeLabel = uilabel(app.ExperimentalparametersPanel);
            app.StepsizeLabel.Text = {'Step'; 'size'};
            app.MaxEditField_2Label_4 = uilabel(app.ExperimentalparametersPanel);
            app.MaxEditField_2Label_4.Text = 'Max';
            app.MinEditField_2Label_4 = uilabel(app.ExperimentalparametersPanel);
            app.MinEditField_2Label_4.Text = 'Min';
            app.StepEditField_2Label = uilabel(app.ExperimentalparametersPanel);
            app.StepEditField_2Label.Text = {'Step'; 'size'};
            app.RewardedtrialsSpinnerLabel = uilabel(app.ExperimentalparametersPanel);
            app.RewardedtrialsSpinnerLabel.Text = 'Rewarded trials (%)';
            app.SpoutstartLabel = uilabel(app.ExperimentalparametersPanel);
            app.SpoutstartLabel.Text = 'Trial start';
            app.CuepenaltymsEditFieldLabel = uilabel(app.ExperimentalparametersPanel);
            app.CuepenaltymsEditFieldLabel.Text = 'Cue penalty (ms)';
            app.SelectiontimemsLabel_2 = uilabel(app.ExperimentalparametersPanel);
            app.SelectiontimemsLabel_2.Text = 'Reward outcome (ms)';
            app.SelectiontimemsLabel_3 = uilabel(app.ExperimentalparametersPanel);
            app.SelectiontimemsLabel_3.Text = 'Cue duration (ms)';
            app.MaxEditFieldLabel_2 = uilabel(app.ExperimentalparametersPanel);
            app.MaxEditFieldLabel_2.Text = 'Max';
            app.MinEditField_2Label_5 = uilabel(app.ExperimentalparametersPanel);
            app.MinEditField_2Label_5.Text = 'Min';
            app.MaxEditFieldLabel_3 = uilabel(app.ExperimentalparametersPanel);
            app.MaxEditFieldLabel_3.Text = 'Max';
            app.MinEditField_2Label_6 = uilabel(app.ExperimentalparametersPanel);
            app.MinEditField_2Label_6.Text = 'Min';
            app.ABTSwitchLabel = uilabel(app.ExperimentalparametersPanel);
            app.ABTSwitchLabel.Text = '2ABT';
            app.PavlovianSwitchLabel = uilabel(app.ExperimentalparametersPanel);
            app.PavlovianSwitchLabel.Text = 'Pavlovian';
            app.LLLRLabel = uilabel(app.ExperimentalparametersPanel);
            app.LLLRLabel.Text = 'LL/LR';
            app.SelectiontimemsLabel_5 = uilabel(app.ExperimentalparametersPanel);
            app.SelectiontimemsLabel_5.Text = 'Reward delay (ms)';
            app.SpoutstartLabel_2 = uilabel(app.ExperimentalparametersPanel);
            app.SpoutstartLabel_2.Text = 'ITI enforced non-lick';
            app.SelectiontimemsLabel_8 = uilabel(app.ExperimentalparametersPanel);
            app.SelectiontimemsLabel_8.Text = 'ENL penalty (ms)';
            app.SpoutstartLabel_3 = uilabel(app.ExperimentalparametersPanel);
            app.SpoutstartLabel_3.Text = 'Block switch after correct';
            app.DelaypenaltymsEditFieldLabel = uilabel(app.ExperimentalparametersPanel);
            app.DelaypenaltymsEditFieldLabel.Text = 'Delay penalty (ms)';
            app.SelectiontimemsLabel_9 = uilabel(app.ExperimentalparametersPanel);
            app.SelectiontimemsLabel_9.Text = 'Delay duration (ms)';
            app.StartcuepenaltymsEditFieldLabel = uilabel(app.ExperimentalparametersPanel);
            app.StartcuepenaltymsEditFieldLabel.Text = 'Start cue penalty (ms)';
            app.SelectiontimemsLabel_10 = uilabel(app.ExperimentalparametersPanel);
            app.SelectiontimemsLabel_10.Text = 'Start cue duration (ms)';
            app.DelayedresponseLabel = uilabel(app.ExperimentalparametersPanel);
            app.DelayedresponseLabel.Text = 'DR';
            app.DRSwitch = uiswitch(app.ExperimentalparametersPanel, 'slider');
            app.DRSwitch.Items = {'off', 'on'};
            app.DRSwitch.ValueChangedFcn = createCallbackFcn(app, @DRSwitchValueChanged, true);
            app.DRSwitch.Value = 'off';
            app.offDR = uilamp(app.ExperimentalparametersPanel);
            app.onDR = uilamp(app.ExperimentalparametersPanel);
            app.LickToStartDropDown = uidropdown(app.ExperimentalparametersPanel);
            app.LickToStartDropDown.Items = {'off', 'on'};
            app.LickToStartDropDown.ValueChangedFcn = createCallbackFcn(app, @LickToStartDropDownValueChanged, true);
            app.LickToStartDropDown.Value = 'off';
            app.SpoutstartLabel_4 = uilabel(app.ExperimentalparametersPanel);
            app.SpoutstartLabel_4.Text = 'Lick after reward';
            app.ExecuteexperimentPanel = uipanel(app.UIFigure);
            app.ExecuteexperimentPanel.Title = 'Execute experiment';
            app.EventLabel_txt = uilabel(app.ExecuteexperimentPanel);
            app.EventLabel_txt.Text = 'Event:';
            app.EventLabel = uilabel(app.ExecuteexperimentPanel);
            app.EventLabel.Text = 'NO SETTINGS LOADED';
            app.BlockEventLabel = uilabel(app.ExecuteexperimentPanel);
            app.BlockEventLabel.Text = 'Block:';
            app.BlockLabel = uilabel(app.ExecuteexperimentPanel);
            app.BlockLabel.Text = 'Idle';
            app.RunTimeLabel = uilabel(app.ExecuteexperimentPanel);
            app.RunTimeLabel.Text = 'Run Time:';
            app.TimeLabel = uilabel(app.ExecuteexperimentPanel);
            app.TimeLabel.Text = '0';
            app.StartexperimentButton = uibutton(app.ExecuteexperimentPanel, 'push');
            app.StartexperimentButton.ButtonPushedFcn = createCallbackFcn(app, @StartexperimentButtonPushed, true);
            app.StartexperimentButton.Text = 'Start experiment';
            app.TaskStartDelayEditField = uieditfield(app.ExecuteexperimentPanel, 'numeric');
            app.TaskStartDelayEditField.Limits = [0 Inf];
            app.TaskStartDelayEditField.ValueDisplayFormat = '%11.5g';
            app.TaskStartDelayEditField.ValueChangedFcn = createCallbackFcn(app, @TaskStartDelayEditFieldValueChanged, true);
            app.SubjectnameEditField = uieditfield(app.ExecuteexperimentPanel, 'text');
            app.SubjectnameEditField.ValueChangedFcn = createCallbackFcn(app, @SubjectnameEditFieldValueChanged, true);
            app.EndRewardsEditField = uieditfield(app.ExecuteexperimentPanel, 'numeric');
            app.EndRewardsEditField.Limits = [0 Inf];
            app.EndRewardsEditField.ValueDisplayFormat = '%11.5g';
            app.EndRewardsEditField.ValueChangedFcn = createCallbackFcn(app, @EndRewardsEditFieldValueChanged, true);
            app.EndTimeoutEditField = uieditfield(app.ExecuteexperimentPanel, 'numeric');
            app.EndTimeoutEditField.Limits = [0 Inf];
            app.EndTimeoutEditField.ValueDisplayFormat = '%11.5g';
            app.EndTimeoutEditField.ValueChangedFcn = createCallbackFcn(app, @EndTimeoutEditFieldValueChanged, true);
            app.EndTrialsEditField = uieditfield(app.ExecuteexperimentPanel, 'numeric');
            app.EndTrialsEditField.Limits = [0 Inf];
            app.EndTrialsEditField.ValueDisplayFormat = '%11.5g';
            app.EndTrialsEditField.ValueChangedFcn = createCallbackFcn(app, @EndTrialsEditFieldValueChanged, true);
            app.EndOmissionsEditField = uieditfield(app.ExecuteexperimentPanel, 'numeric');
            app.EndOmissionsEditField.Limits = [0 Inf];
            app.EndOmissionsEditField.ValueDisplayFormat = '%11.5g';
            app.EndOmissionsEditField.ValueChangedFcn = createCallbackFcn(app, @EndOmissionsEditFieldValueChanged, true);
            app.EndTrialButton = uibutton(app.ExecuteexperimentPanel, 'state');
            app.EndTrialButton.Text = 'End of trial';
            app.EndBlockButton = uibutton(app.ExecuteexperimentPanel, 'state');
            app.EndBlockButton.Text = 'End of block';
            app.ImmediatestopButton = uibutton(app.ExecuteexperimentPanel, 'push');
            app.ImmediatestopButton.ButtonPushedFcn = createCallbackFcn(app, @ImmediatestopButtonPushed, true);
            app.ImmediatestopButton.Text = 'Immediate stop';
            app.OmissionsEditFieldLabel = uilabel(app.ExecuteexperimentPanel);
            app.OmissionsEditFieldLabel.Text = 'Omissions:';
            app.TimeoutminLabel = uilabel(app.ExecuteexperimentPanel);
            app.TimeoutminLabel.Text = 'Time (min):';
            app.SubjectnameEditFieldLabel = uilabel(app.ExecuteexperimentPanel);
            app.SubjectnameEditFieldLabel.Text = 'Subject name';
            app.ResavedataButton = uibutton(app.ExecuteexperimentPanel, 'push');
            app.ResavedataButton.ButtonPushedFcn = createCallbackFcn(app, @ResavedataButtonPushed, true);
            app.ResavedataButton.Text = 'Re-save data';
            app.TimeoutminLabel_2 = uilabel(app.ExecuteexperimentPanel);
            app.TimeoutminLabel_2.Text = 'Rewards:';
            app.TimeoutminLabel_3 = uilabel(app.ExecuteexperimentPanel);
            app.TimeoutminLabel_3.Text = 'Trials:';
            app.TimeoutminLabel_4 = uilabel(app.ExecuteexperimentPanel);
            app.TimeoutminLabel_4.Text = 'Delay start session (sec)';
            app.LoadsettingsButton = uibutton(app.ExecuteexperimentPanel, 'push');
            app.LoadsettingsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadsettingsButtonPushed, true);
            app.LoadsettingsButton.Text = 'Load settings';
            app.ManuallyendsessionLabel = uilabel(app.ExecuteexperimentPanel);
            app.ManuallyendsessionLabel.Text = 'Manually end session';
            app.AutomaticallyendsessionLabel = uilabel(app.ExecuteexperimentPanel);
            app.AutomaticallyendsessionLabel.Text = 'Automatically end session';
            app.LoadandstartsessionLabel = uilabel(app.ExecuteexperimentPanel);
            app.LoadandstartsessionLabel.Text = 'Load and start session';
            app.ManualtriggeringPanel = uipanel(app.UIFigure);
            app.ManualtriggeringPanel.Title = 'Manual triggering';
            app.LeftCuefreqEditField = uieditfield(app.ManualtriggeringPanel, 'numeric');
            app.LeftCuefreqEditField.Limits = [0 Inf];
            app.LeftCuefreqEditField.ValueDisplayFormat = '%11.5g';
            app.LeftCuefreqEditField.ValueChangedFcn = createCallbackFcn(app, @LeftCuefreqEditFieldValueChanged, true);
            app.RightCuefreqEditField = uieditfield(app.ManualtriggeringPanel, 'numeric');
            app.RightCuefreqEditField.Limits = [0 Inf];
            app.RightCuefreqEditField.ValueDisplayFormat = '%11.5g';
            app.RightCuefreqEditField.ValueChangedFcn = createCallbackFcn(app, @RightCuefreqEditFieldValueChanged, true);
            app.LeftCalibrateEditField = uispinner(app.ManualtriggeringPanel);
            app.LeftCalibrateEditField.Limits = [1 Inf];
            app.LeftCalibrateEditField.ValueChangedFcn = createCallbackFcn(app, @LeftCalibrateEditFieldValueChanged, true);
            app.LeftCalibrateEditField.Value = 1;
            app.RightCalibrateEditField = uispinner(app.ManualtriggeringPanel);
            app.RightCalibrateEditField.Limits = [1 Inf];
            app.RightCalibrateEditField.ValueChangedFcn = createCallbackFcn(app, @RightCalibrateEditFieldValueChanged, true);
            app.RightCalibrateEditField.Value = 1;
            app.CuesinstructedSwitch = uiswitch(app.ManualtriggeringPanel, 'slider');
            app.CuesinstructedSwitch.Items = {'off', 'on'};
            app.CuesinstructedSwitch.ValueChangedFcn = createCallbackFcn(app, @CuesinstructedSwitchValueChanged, true);
            app.CuesinstructedSwitch.Value = 'on';
            app.TriggercueandmanualwaterSwitch = uiswitch(app.ManualtriggeringPanel, 'slider');
            app.TriggercueandmanualwaterSwitch.Items = {'off', 'on'};
            app.TriggercueandmanualwaterSwitch.ValueChangedFcn = createCallbackFcn(app, @TriggercueandmanualwaterSwitchValueChanged, true);
            app.TriggercueandmanualwaterSwitch.Value = 'off';
            app.ManualLeftRewardsLabel = uilabel(app.ManualtriggeringPanel);
            app.ManualLeftRewardsLabel.Text = '0';
            app.ManualRightRewardsLabel = uilabel(app.ManualtriggeringPanel);
            app.ManualRightRewardsLabel.Text = '0';
            app.LicksLeftLabel = uilabel(app.ManualtriggeringPanel);
            app.LicksLeftLabel.Text = 'Licks';
            app.LicksRightLabel = uilabel(app.ManualtriggeringPanel);
            app.LicksRightLabel.Text = 'Licks';
            app.CuefrequencyhzLabel = uilabel(app.ManualtriggeringPanel);
            app.CuefrequencyhzLabel.Text = 'Cue frequency (hz)';
            app.LeftcueLabel = uilabel(app.ManualtriggeringPanel);
            app.LeftcueLabel.Text = 'Left cue';
            app.RightcueLabel = uilabel(app.ManualtriggeringPanel);
            app.RightcueLabel.Text = 'Right cue';
            app.LeftCueButton = uibutton(app.ManualtriggeringPanel, 'push');
            app.LeftCueButton.ButtonPushedFcn = createCallbackFcn(app, @LeftCueButtonPushed, true);
            app.LeftCueButton.Text = 'Cue';
            app.RightCueButton = uibutton(app.ManualtriggeringPanel, 'push');
            app.RightCueButton.ButtonPushedFcn = createCallbackFcn(app, @RightCueButtonPushed, true);
            app.RightCueButton.Text = 'Cue';
            app.LeftWaterButton = uibutton(app.ManualtriggeringPanel, 'push');
            app.LeftWaterButton.ButtonPushedFcn = createCallbackFcn(app, @LeftWaterButtonPushed, true);
            app.LeftWaterButton.Text = 'Water';
            app.RightWaterButton = uibutton(app.ManualtriggeringPanel, 'push');
            app.RightWaterButton.ButtonPushedFcn = createCallbackFcn(app, @RightWaterButtonPushed, true);
            app.RightWaterButton.Text = 'Water';
            app.LicksResetButton = uibutton(app.ManualtriggeringPanel, 'push');
            app.LicksResetButton.ButtonPushedFcn = createCallbackFcn(app, @LicksResetButtonPushed, true);
            app.LicksResetButton.Text = 'Reset';
            app.LicksLeftCounter = uilabel(app.ManualtriggeringPanel);
            app.LicksLeftCounter.Text = '0';
            app.LicksRightCounter = uilabel(app.ManualtriggeringPanel);
            app.LicksRightCounter.Text = '0';
            app.LCalibrateButton = uibutton(app.ManualtriggeringPanel, 'state');
            app.LCalibrateButton.ValueChangedFcn = createCallbackFcn(app, @LCalibrateButtonValueChanged, true);
            app.LCalibrateButton.Text = 'Calibrate';
            app.RCalibrateButton = uibutton(app.ManualtriggeringPanel, 'state');
            app.RCalibrateButton.ValueChangedFcn = createCallbackFcn(app, @RCalibrateButtonValueChanged, true);
            app.RCalibrateButton.Text = 'Calibrate';
            app.TriggercueandmanualwaterSwitchLabel = uilabel(app.ManualtriggeringPanel);
            app.TriggercueandmanualwaterSwitchLabel.Text = {'Trigger cue '; 'and manual water'};
            app.LeftsolenoidLabel = uilabel(app.ManualtriggeringPanel);
            app.LeftsolenoidLabel.Text = {'Solenoid'; 'duration (ms)'};
            app.RightsolenoiddurationmsLabel = uilabel(app.ManualtriggeringPanel);
            app.RightsolenoiddurationmsLabel.Text = {'Solenoid'; 'duration (ms)'};
            app.CuesinstructedSwitchLabel = uilabel(app.ManualtriggeringPanel);
            app.CuesinstructedSwitchLabel.Text = 'Cues instructed';
            app.ManualRewardsLeftLabel = uilabel(app.ManualtriggeringPanel);
            app.ManualRewardsLeftLabel.Text = {'Manual'; 'rewards'};
            app.ManualRewardsRightLabel = uilabel(app.ManualtriggeringPanel);
            app.ManualRewardsRightLabel.Text = {'Manual'; 'rewards'};
            app.offCueInst = uilamp(app.ManualtriggeringPanel);
            app.onCueInst = uilamp(app.ManualtriggeringPanel);
            app.offCueRew = uilamp(app.ManualtriggeringPanel);
            app.onCueRew = uilamp(app.ManualtriggeringPanel);
            app.StartcueparametersLabel = uilabel(app.ManualtriggeringPanel);
            app.StartcueparametersLabel.Text = 'Start cue parameters';
            app.LeftRightcueparametersLabel = uilabel(app.ManualtriggeringPanel);
            app.LeftRightcueparametersLabel.Text = 'Left/Right cue parameters';
            app.StartCuefreqEditField = uieditfield(app.ManualtriggeringPanel, 'numeric');
            app.StartCuefreqEditField.Limits = [0 Inf];
            app.StartCuefreqEditField.ValueDisplayFormat = '%11.5g';
            app.StartCuefreqEditField.ValueChangedFcn = createCallbackFcn(app, @StartCuefreqEditFieldValueChanged, true);
            app.StartcueFreqhzLabel = uilabel(app.ManualtriggeringPanel);
            app.StartcueFreqhzLabel.Text = 'Start cue Freq (hz)';
            app.StartCueButton = uibutton(app.ManualtriggeringPanel, 'push');
            app.StartCueButton.ButtonPushedFcn = createCallbackFcn(app, @StartCueButtonPushed, true);
            app.StartCueButton.Text = 'Start cue';
            app.NumTrials = uilabel(app.UIFigure);
            app.NumTrials.Text = 'Num trials:';
            app.TotRew = uilabel(app.UIFigure);
            app.TotRew.Text = 'Num rewards:';
            app.NumBlock = uilabel(app.UIFigure);
            app.NumBlock.Text = 'Num blocks:';
            app.RewBlock = uilabel(app.UIFigure);
            app.RewBlock.Text = 'Reward in block:';
            app.slash = uilabel(app.UIFigure);
            app.slash.Text = '/';
            app.NumTrialsLabel = uilabel(app.UIFigure);
            app.NumTrialsLabel.Text = '0';
            app.RewBlockLabel = uilabel(app.UIFigure);
            app.RewBlockLabel.Text = '0';
            app.NumBlockLabel = uilabel(app.UIFigure);
            app.NumBlockLabel.Text = '0';
            app.TotRewLabel = uilabel(app.UIFigure);
            app.TotRewLabel.Text = '0';
            app.OutOfLabel = uilabel(app.UIFigure);
            app.OutOfLabel.Text = '0';
            app.FreeRew = uilabel(app.UIFigure);
            app.FreeRew.Text = 'Free rewards:';
            app.FreeRewLabel = uilabel(app.UIFigure);
            app.FreeRewLabel.Text = '0';
            app.TrialInBlock = uilabel(app.UIFigure);
            app.TrialInBlock.Text = 'Trial in block:';
            app.TrialInBlockLabel = uilabel(app.UIFigure);
            app.TrialInBlockLabel.Text = '0';
            app.SessioninformationLabel = uilabel(app.UIFigure);
            app.SessioninformationLabel.Text = 'Session information';
            app.TrialperformanceLabel = uilabel(app.UIFigure);
            app.TrialperformanceLabel.Text = 'Trial performance';
            app.RewadProb = uilabel(app.UIFigure);
            app.RewadProb.Text = 'Reward prob:';
            app.RewardProbability = uilabel(app.UIFigure);
            app.RewardProbability.Text = '0';
            app.RewadSize = uilabel(app.UIFigure);
            app.RewadSize.Text = 'Reward size:';
            app.RewardSizeLabel = uilabel(app.UIFigure);
            app.RewardSizeLabel.Text = '0';
            app.RightTrials2Reward = uilabel(app.UIFigure);
            app.RightTrials2Reward.Text = 'Right t2r:';
            app.RightTrials2RewardLabel = uilabel(app.UIFigure);
            app.RightTrials2RewardLabel.Text = '0';
            app.LeftTrials2Reward = uilabel(app.UIFigure);
            app.LeftTrials2Reward.Text = 'Left t2r:';
            app.LeftTrials2RewardLabel = uilabel(app.UIFigure);
            app.LeftTrials2RewardLabel.Text = '0';
            app.CurrenttrialinformationLabel = uilabel(app.UIFigure);
            app.CurrenttrialinformationLabel.Text = 'Current trial information';
            app.OptogeneticsparametersPanel = uipanel(app.UIFigure);
            app.OptogeneticsparametersPanel.Title = 'Optogenetics parameters';
            app.WhentostimulateLabel = uilabel(app.OptogeneticsparametersPanel);
            app.WhentostimulateLabel.Text = 'When to stimulate';
            app.OptogeneticstimulationSwitch = uiswitch(app.OptogeneticsparametersPanel, 'slider');
            app.OptogeneticstimulationSwitch.Items = {'off', 'on'};
            app.OptogeneticstimulationSwitch.ValueChangedFcn = createCallbackFcn(app, @OptogeneticstimulationSwitchValueChanged, true);
            app.OptogeneticstimulationSwitch.Value = 'off';
            app.OptoLabel = uilabel(app.OptogeneticsparametersPanel);
            app.OptoLabel.Text = 'Optogenetic stimulation:';
            app.offOpto = uilamp(app.OptogeneticsparametersPanel);
            app.onOpto = uilamp(app.OptogeneticsparametersPanel);
            app.StimulationduringLabel = uilabel(app.OptogeneticsparametersPanel);
            app.StimulationduringLabel.Text = 'Stimulation during:';
            app.OptoAtBlockswitchCheckBox = uicheckbox(app.OptogeneticsparametersPanel);
            app.OptoAtBlockswitchCheckBox.ValueChangedFcn = createCallbackFcn(app, @OptoAtBlockswitchCheckBoxValueChanged, true);
            app.OptoAtBlockswitchCheckBox.Text = '   Block switch (◆)';
            app.OptoAtITICheckBox = uicheckbox(app.OptogeneticsparametersPanel);
            app.OptoAtITICheckBox.ValueChangedFcn = createCallbackFcn(app, @OptoAtITICheckBoxValueChanged, true);
            app.OptoAtITICheckBox.Text = '   ITI (▲)';
            app.OptoAtCueCheckBox = uicheckbox(app.OptogeneticsparametersPanel);
            app.OptoAtCueCheckBox.ValueChangedFcn = createCallbackFcn(app, @OptoAtCueCheckBoxValueChanged, true);
            app.OptoAtCueCheckBox.Text = '   Cue (▶)';
            app.OptoAtOutcomeCheckBox = uicheckbox(app.OptogeneticsparametersPanel);
            app.OptoAtOutcomeCheckBox.ValueChangedFcn = createCallbackFcn(app, @OptoAtOutcomeCheckBoxValueChanged, true);
            app.OptoAtOutcomeCheckBox.Text = '   Outcome (■)';
            app.OptoAtRewardCheckBox = uicheckbox(app.OptogeneticsparametersPanel);
            app.OptoAtRewardCheckBox.ValueChangedFcn = createCallbackFcn(app, @OptoAtRewardCheckBoxValueChanged, true);
            app.OptoAtRewardCheckBox.Text = '   Reward (★)';
            app.OptoAtErrorCheckBox = uicheckbox(app.OptogeneticsparametersPanel);
            app.OptoAtErrorCheckBox.ValueChangedFcn = createCallbackFcn(app, @OptoAtErrorCheckBoxValueChanged, true);
            app.OptoAtErrorCheckBox.Text = '   Error (✶)';
            app.HowtostimulateLabel = uilabel(app.OptogeneticsparametersPanel);
            app.HowtostimulateLabel.Text = 'How to stimulate';
            app.OptoprobabilityLabel = uilabel(app.OptogeneticsparametersPanel);
            app.OptoprobabilityLabel.Text = 'Trials with stimulation (%)';
            app.TrialswithstimulationSpinner = uispinner(app.OptogeneticsparametersPanel);
            app.TrialswithstimulationSpinner.Step = 5;
            app.TrialswithstimulationSpinner.Limits = [0 100];
            app.TrialswithstimulationSpinner.ValueChangedFcn = createCallbackFcn(app, @TrialswithstimulationSpinnerValueChanged, true);
            app.CorrecttrialsbeforestimulationEditFieldLabel = uilabel(app.OptogeneticsparametersPanel);
            app.CorrecttrialsbeforestimulationEditFieldLabel.Text = 'Correct trials before stimulation';
            app.CorrecttrialsbeforestimulationEditField = uieditfield(app.OptogeneticsparametersPanel, 'numeric');
            app.CorrecttrialsbeforestimulationEditField.Limits = [0 Inf];
            app.CorrecttrialsbeforestimulationEditField.ValueDisplayFormat = '%11.5g';
            app.CorrecttrialsbeforestimulationEditField.ValueChangedFcn = createCallbackFcn(app, @CorrecttrialsbeforestimulationEditFieldValueChanged, true);
            app.StimulationdelaymsEditFieldLabel = uilabel(app.OptogeneticsparametersPanel);
            app.StimulationdelaymsEditFieldLabel.Text = 'Stimulation delay (ms)';
            app.StimulationdelaymsEditField = uieditfield(app.OptogeneticsparametersPanel, 'numeric');
            app.StimulationdelaymsEditField.Limits = [0 Inf];
            app.StimulationdelaymsEditField.ValueDisplayFormat = '%11.5g';
            app.StimulationdelaymsEditField.ValueChangedFcn = createCallbackFcn(app, @StimulationdelaymsEditFieldValueChanged, true);
            app.AllowmultistimLabel = uilabel(app.OptogeneticsparametersPanel);
            app.AllowmultistimLabel.Text = 'Allow multi-stim:';
            app.MultiStimInblockCheckBox = uicheckbox(app.OptogeneticsparametersPanel);
            app.MultiStimInblockCheckBox.ValueChangedFcn = createCallbackFcn(app, @MultiStimInblockCheckBoxValueChanged, true);
            app.MultiStimInblockCheckBox.Text = '   In block';
            app.MultiStimITIpenaltyCheckBox = uicheckbox(app.OptogeneticsparametersPanel);
            app.MultiStimITIpenaltyCheckBox.ValueChangedFcn = createCallbackFcn(app, @MultiStimITIpenaltyCheckBoxValueChanged, true);
            app.MultiStimITIpenaltyCheckBox.Text = '   ENL penalty';
            app.MultiStimCuepenaltyCheckBox = uicheckbox(app.OptogeneticsparametersPanel);
            app.MultiStimCuepenaltyCheckBox.ValueChangedFcn = createCallbackFcn(app, @MultiStimCuepenaltyCheckBoxValueChanged, true);
            app.MultiStimCuepenaltyCheckBox.Text = '   Cue penalty';
            app.PulsemodulationLabel = uilabel(app.OptogeneticsparametersPanel);
            app.PulsemodulationLabel.Text = 'Pulse modulation';
            app.ContinuousstimulationCheckBox = uicheckbox(app.OptogeneticsparametersPanel);
            app.ContinuousstimulationCheckBox.ValueChangedFcn = createCallbackFcn(app, @ContinuousstimulationCheckBoxValueChanged, true);
            app.ContinuousstimulationCheckBox.Text = '   Continuous stimulation';
            app.StimulationdurationmsEditFieldLabel = uilabel(app.OptogeneticsparametersPanel);
            app.StimulationdurationmsEditFieldLabel.Text = 'Stimulation duration (ms)';
            app.StimulationdurationmsEditField = uieditfield(app.OptogeneticsparametersPanel, 'numeric');
            app.StimulationdurationmsEditField.Limits = [0 Inf];
            app.StimulationdurationmsEditField.ValueDisplayFormat = '%11.5g';
            app.StimulationdurationmsEditField.ValueChangedFcn = createCallbackFcn(app, @StimulationdurationmsEditFieldValueChanged, true);
            app.PulsedurationmsEditFieldLabel = uilabel(app.OptogeneticsparametersPanel);
            app.PulsedurationmsEditFieldLabel.Text = 'Pulse duration (ms)';
            app.PulsedurationmsEditField = uieditfield(app.OptogeneticsparametersPanel, 'numeric');
            app.PulsedurationmsEditField.Limits = [0 Inf];
            app.PulsedurationmsEditField.ValueDisplayFormat = '%11.5g';
            app.PulsedurationmsEditField.ValueChangedFcn = createCallbackFcn(app, @PulsedurationmsEditFieldValueChanged, true);
            app.StimulationfrequencyHzEditFieldLabel = uilabel(app.OptogeneticsparametersPanel);
            app.StimulationfrequencyHzEditFieldLabel.Text = 'Stimulation frequency (Hz)';
            app.StimulationfrequencyHzEditField = uieditfield(app.OptogeneticsparametersPanel, 'numeric');
            app.StimulationfrequencyHzEditField.Limits = [0 Inf];
            app.StimulationfrequencyHzEditField.ValueDisplayFormat = '%11.5g';
            app.StimulationfrequencyHzEditField.ValueChangedFcn = createCallbackFcn(app, @StimulationfrequencyHzEditFieldValueChanged, true);
            app.MiscellaneousparametersLabel = uilabel(app.OptogeneticsparametersPanel);
            app.MiscellaneousparametersLabel.Text = 'Miscellaneous parameters';
            app.TeststimulationSwitch = uiswitch(app.OptogeneticsparametersPanel, 'slider');
            app.TeststimulationSwitch.Items = {'off', 'on'};
            app.TeststimulationSwitch.ValueChangedFcn = createCallbackFcn(app, @TeststimulationSwitchValueChanged, true);
            app.TeststimulationSwitch.Value = 'off';
            app.TeststimulationSwitchLabel = uilabel(app.OptogeneticsparametersPanel);
            app.TeststimulationSwitchLabel.Text = 'Test stimulation';
            app.offOptoManual = uilamp(app.OptogeneticsparametersPanel);
            app.onOptoManual = uilamp(app.OptogeneticsparametersPanel);
            app.AOMmodulationLabel = uilabel(app.OptogeneticsparametersPanel);
            app.AOMmodulationLabel.Text = 'Power modulation (%)';
            app.PowermodulationSpinner = uispinner(app.OptogeneticsparametersPanel);
            app.PowermodulationSpinner.Limits = [0 100];
            app.PowermodulationSpinner.ValueChangedFcn = createCallbackFcn(app, @PowermodulationSpinnerValueChanged, true);
            app.ConstantmodulationSwitchLabel = uilabel(app.OptogeneticsparametersPanel);
            app.ConstantmodulationSwitchLabel.Text = 'Constant modulation';
            app.ConstantmodulationSwitch = uiswitch(app.OptogeneticsparametersPanel, 'slider');
            app.ConstantmodulationSwitch.Items = {'off', 'on'};
            app.ConstantmodulationSwitch.ValueChangedFcn = createCallbackFcn(app, @ConstantmodulationSwitchValueChanged, true);
            app.ConstantmodulationSwitch.Value = 'on';
            app.offOptoMod = uilamp(app.OptogeneticsparametersPanel);
            app.onOptoMod = uilamp(app.OptogeneticsparametersPanel);
            app.TaperstimulationoffmsEditFieldLabel = uilabel(app.OptogeneticsparametersPanel);
            app.TaperstimulationoffmsEditFieldLabel.Text = 'Taper stimulation off (ms)';
            app.TaperstimulationoffmsEditField = uieditfield(app.OptogeneticsparametersPanel, 'numeric');
            app.TaperstimulationoffmsEditField.Limits = [0 Inf];
            app.TaperstimulationoffmsEditField.ValueDisplayFormat = '%11.5g';
            app.TaperstimulationoffmsEditField.ValueChangedFcn = createCallbackFcn(app, @TaperstimulationoffmsEditFieldValueChanged, true);
            app.AdddistractionlightCheckBox = uicheckbox(app.OptogeneticsparametersPanel);
            app.AdddistractionlightCheckBox.ValueChangedFcn = createCallbackFcn(app, @AdddistractionlightCheckBoxValueChanged, true);
            app.AdddistractionlightCheckBox.Text = '   Add distraction light';
            app.OptoAtStartCueCheckBox = uicheckbox(app.OptogeneticsparametersPanel);
            app.OptoAtStartCueCheckBox.ValueChangedFcn = createCallbackFcn(app, @OptoAtStartCueCheckBoxValueChanged, true);
            app.OptoAtStartCueCheckBox.Text = '   Start Cue (◀)';
            app.OptoAtDelayCheckBox = uicheckbox(app.OptogeneticsparametersPanel);
            app.OptoAtDelayCheckBox.ValueChangedFcn = createCallbackFcn(app, @OptoAtDelayCheckBoxValueChanged, true);
            app.OptoAtDelayCheckBox.Text = '   Delay (▼)';
            app.MultiStimDelaypenaltyCheckBox = uicheckbox(app.OptogeneticsparametersPanel);
            app.MultiStimDelaypenaltyCheckBox.ValueChangedFcn = createCallbackFcn(app, @MultiStimDelaypenaltyCheckBoxValueChanged, true);
            app.MultiStimDelaypenaltyCheckBox.Text = '   Delay penalty';
            app.MultiStimStartCuepenaltyCheckBox = uicheckbox(app.OptogeneticsparametersPanel);
            app.MultiStimStartCuepenaltyCheckBox.ValueChangedFcn = createCallbackFcn(app, @MultiStimStartCuepenaltyCheckBoxValueChanged, true);
            app.MultiStimStartCuepenaltyCheckBox.Text = '   Start cue penalty';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'SPOUT2_logo.png');
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.WillstopexperimentbutwillnotsavedataMenu = uimenu(app.ContextMenu);
            app.WillstopexperimentbutwillnotsavedataMenu.Text = 'Will stop experiment, but will not save data';
            % Assign app.ContextMenu
            app.ImmediatestopButton.ContextMenu = app.ContextMenu;
            app.ContextMenu2 = uicontextmenu(app.UIFigure);
            app.WillstopexperimentattheendofthecurrentblockMenu = uimenu(app.ContextMenu2);
            app.WillstopexperimentattheendofthecurrentblockMenu.Text = 'Will stop experiment at the end of the current block';
            % Assign app.ContextMenu2
            app.EndBlockButton.ContextMenu = app.ContextMenu2;
            app.ContextMenu3 = uicontextmenu(app.UIFigure);
            app.WillstopexperimentattheendofthecurrenttrialMenu = uimenu(app.ContextMenu3);
            app.WillstopexperimentattheendofthecurrenttrialMenu.Text = 'Will stop experiment at the end of the current trial';
            % Assign app.ContextMenu3
            app.EndTrialButton.ContextMenu = app.ContextMenu3;
            app.ContextMenu4 = uicontextmenu(app.UIFigure);
            app.StartexperimentMenu = uimenu(app.ContextMenu4);
            app.StartexperimentMenu.Text = 'Start experiment';
            % Assign app.ContextMenu4
            app.StartexperimentButton.ContextMenu = app.ContextMenu4;
            app.LoadsettingsButton.ContextMenu = app.ContextMenu4;
            app.ContextMenu5 = uicontextmenu(app.UIFigure);
            app.MinimumnumberoftrialsineachblockMenu = uimenu(app.ContextMenu5);
            app.MinimumnumberoftrialsineachblockMenu.Text = 'Minimum number of trials in each block';
            % Assign app.ContextMenu5
            app.MinTrialsEditField.ContextMenu = app.ContextMenu5;
            app.MinTrialsLeftEditField.ContextMenu = app.ContextMenu5;
            app.MinTrialsRightEditField.ContextMenu = app.ContextMenu5;
            app.ContextMenu6 = uicontextmenu(app.UIFigure);
            app.MaximumnumberoftrialsperblockMenu = uimenu(app.ContextMenu6);
            app.MaximumnumberoftrialsperblockMenu.Text = 'Maximum number of trials per block';
            % Assign app.ContextMenu6
            app.MaxTrialsEditField.ContextMenu = app.ContextMenu6;
            app.MaxTrialsLeftEditField.ContextMenu = app.ContextMenu6;
            app.MaxTrialsRightEditField.ContextMenu = app.ContextMenu6;
            app.ContextMenu7 = uicontextmenu(app.UIFigure);
            app.Menu2 = uimenu(app.ContextMenu7);
            app.Menu2.Text = 'Number of consecutive incorrect trials and/or omissions before a reward from the correct spout is given';
            % Assign app.ContextMenu7
            app.Fails2RewEditField.ContextMenu = app.ContextMenu7;
            app.ContextMenu8 = uicontextmenu(app.UIFigure);
            app.AmountoftimeanimalhastomakeaselectionbeforeanomissionMenu = uimenu(app.ContextMenu8);
            app.AmountoftimeanimalhastomakeaselectionbeforeanomissionMenu.Text = 'Amount of time animal has to make a selection before an omission';
            % Assign app.ContextMenu8
            app.ENLPenaltyDurationEditField.ContextMenu = app.ContextMenu8;
            app.CueTimeEditField.ContextMenu = app.ContextMenu8;
            app.DelayDurationEditField.ContextMenu = app.ContextMenu8;
            app.StartCueDurationEditField.ContextMenu = app.ContextMenu8;
            app.SelectionTimeEditField.ContextMenu = app.ContextMenu8;
            app.RewardOutcomeTimeEditField.ContextMenu = app.ContextMenu8;
            app.NoRewOutcomeTimeEditField.ContextMenu = app.ContextMenu8;
            app.RewardDelayEditField.ContextMenu = app.ContextMenu8;
            app.ErrorLightDurationEditField.ContextMenu = app.ContextMenu8;
            app.ContextMenu9 = uicontextmenu(app.UIFigure);
            app.Menu2_2 = uimenu(app.ContextMenu9);
            app.Menu2_2.Text = 'Amount of time from cue that cue penalty period lasts. If animal licks during cue penalty period, return to ENL period';
            % Assign app.ContextMenu9
            app.CuepenaltymsEditField.ContextMenu = app.ContextMenu9;
            app.DelaypenaltymsEditField.ContextMenu = app.ContextMenu9;
            app.StartcuepenaltymsEditField.ContextMenu = app.ContextMenu9;
            app.ContextMenu10 = uicontextmenu(app.UIFigure);
            app.MinimumITIdurationMenu = uimenu(app.ContextMenu10);
            app.MinimumITIdurationMenu.Text = 'Minimum ITI duration';
            % Assign app.ContextMenu10
            app.ITIMinEditField.ContextMenu = app.ContextMenu10;
            app.ContextMenu11 = uicontextmenu(app.UIFigure);
            app.MaximumITIdurationMenu = uimenu(app.ContextMenu11);
            app.MaximumITIdurationMenu.Text = 'Maximum ITI duration';
            % Assign app.ContextMenu11
            app.ITIMaxEditField.ContextMenu = app.ContextMenu11;
            app.ContextMenu12 = uicontextmenu(app.UIFigure);
            app.MinimumleftrewardsizedurationsolenoidsareopenforMenu = uimenu(app.ContextMenu12);
            app.MinimumleftrewardsizedurationsolenoidsareopenforMenu.Text = 'Minimum left reward size (duration solenoids are open for)';
            % Assign app.ContextMenu12
            app.LeftMinEditField.ContextMenu = app.ContextMenu12;
            app.ContextMenu13 = uicontextmenu(app.UIFigure);
            app.MaximumleftrewardsizedurationsolenoidsareopenforMenu = uimenu(app.ContextMenu13);
            app.MaximumleftrewardsizedurationsolenoidsareopenforMenu.Text = 'Maximum left reward size (duration solenoids are open for)';
            % Assign app.ContextMenu13
            app.LeftMaxEditField.ContextMenu = app.ContextMenu13;
            app.ContextMenu14 = uicontextmenu(app.UIFigure);
            app.MinimumrightrewardsizedurationsolenoidsareopenforMenu = uimenu(app.ContextMenu14);
            app.MinimumrightrewardsizedurationsolenoidsareopenforMenu.Text = 'Minimum right reward size (duration solenoids are open for)';
            % Assign app.ContextMenu14
            app.RightMinEditField.ContextMenu = app.ContextMenu14;
            app.ContextMenu15 = uicontextmenu(app.UIFigure);
            app.MaximumrightrewardsizedurationsolenoidsareopenforMenu = uimenu(app.ContextMenu15);
            app.MaximumrightrewardsizedurationsolenoidsareopenforMenu.Text = 'Maximum right reward size (duration solenoids are open for)';
            % Assign app.ContextMenu15
            app.RightMaxEditField.ContextMenu = app.ContextMenu15;
            app.ContextMenu16 = uicontextmenu(app.UIFigure);
            app.ProbabilitythatarewardisgivenintheeventofacorrectlickMenu = uimenu(app.ContextMenu16);
            app.ProbabilitythatarewardisgivenintheeventofacorrectlickMenu.Text = 'Probability that a reward is given in the event of a correct lick';
            % Assign app.ContextMenu16
            app.RewardedtrialsSpinner.ContextMenu = app.ContextMenu16;
            app.ContextMenu17 = uicontextmenu(app.UIFigure);
            app.WhichblocktheexperimentbeginsonMenu = uimenu(app.ContextMenu17);
            app.WhichblocktheexperimentbeginsonMenu.Text = 'Which block the experiment begins on';
            % Assign app.ContextMenu17
            app.TrialStart.ContextMenu = app.ContextMenu17;
            app.BlockSwitchAfterCorrectTrials.ContextMenu = app.ContextMenu17;
            app.ITI_ENL.ContextMenu = app.ContextMenu17;
            app.LickToStartDropDown.ContextMenu = app.ContextMenu17;
            app.ContextMenu18 = uicontextmenu(app.UIFigure);
            app.NameoftheanimalUsedtonamethebehavioraldatafilesMenu = uimenu(app.ContextMenu18);
            app.NameoftheanimalUsedtonamethebehavioraldatafilesMenu.Text = 'Name of the animal. Used to name the behavioral data files';
            % Assign app.ContextMenu18
            app.SubjectnameEditField.ContextMenu = app.ContextMenu18;
            app.ContextMenu19 = uicontextmenu(app.UIFigure);
            app.ExperimentrunningtimerMenu = uimenu(app.ContextMenu19);
            app.ExperimentrunningtimerMenu.Text = 'Experiment running timer';
            % Assign app.ContextMenu19
            app.TimeLabel.ContextMenu = app.ContextMenu19;
            app.ContextMenu20 = uicontextmenu(app.UIFigure);
            app.ResavebehavioraldatacollectedMenu = uimenu(app.ContextMenu20);
            app.ResavebehavioraldatacollectedMenu.Text = 'Re-save behavioral data collected';
            % Assign app.ContextMenu20
            app.ResavedataButton.ContextMenu = app.ContextMenu20;
            app.ContextMenu22 = uicontextmenu(app.UIFigure);
            app.Menu2_4 = uimenu(app.ContextMenu22);
            app.Menu2_4.Text = 'Maximum number of consecutive omissions to cause experiment to end. Default value is 20';
            % Assign app.ContextMenu22
            app.EndOmissionsEditField.ContextMenu = app.ContextMenu22;
            app.ContextMenu23 = uicontextmenu(app.UIFigure);
            app.Menu2_5 = uimenu(app.ContextMenu23);
            app.Menu2_5.Text = 'Maximum number of minutes elapsed to cause experiment to end. Default value is 60';
            % Assign app.ContextMenu23
            app.TaskStartDelayEditField.ContextMenu = app.ContextMenu23;
            app.EndRewardsEditField.ContextMenu = app.ContextMenu23;
            app.EndTimeoutEditField.ContextMenu = app.ContextMenu23;
            app.EndTrialsEditField.ContextMenu = app.ContextMenu23;
            app.ContextMenu24 = uicontextmenu(app.UIFigure);
            app.WhateventtheexperimentiscurrentlyonMenu = uimenu(app.ContextMenu24);
            app.WhateventtheexperimentiscurrentlyonMenu.Text = 'What event the experiment is currently on';
            % Assign app.ContextMenu24
            app.EventLabel.ContextMenu = app.ContextMenu24;
            app.ContextMenu25 = uicontextmenu(app.UIFigure);
            app.WhichblocksidetheexperimentiscurrentlyonMenu = uimenu(app.ContextMenu25);
            app.WhichblocksidetheexperimentiscurrentlyonMenu.Text = 'Which block side the experiment is currently on';
            % Assign app.ContextMenu25
            app.BlockLabel.ContextMenu = app.ContextMenu25;
            app.ContextMenu26 = uicontextmenu(app.UIFigure);
            app.FrequencyofleftcueMenu = uimenu(app.ContextMenu26);
            app.FrequencyofleftcueMenu.Text = 'Frequency of left cue';
            % Assign app.ContextMenu26
            app.LeftCuefreqEditField.ContextMenu = app.ContextMenu26;
            app.ContextMenu27 = uicontextmenu(app.UIFigure);
            app.FrequencyofrightcueMenu = uimenu(app.ContextMenu27);
            app.FrequencyofrightcueMenu.Text = 'Frequency of right cue';
            % Assign app.ContextMenu27
            app.RightCuefreqEditField.ContextMenu = app.ContextMenu27;
            app.StartCuefreqEditField.ContextMenu = app.ContextMenu27;
            app.ContextMenu28 = uicontextmenu(app.UIFigure);
            app.ManualleftcueMenu = uimenu(app.ContextMenu28);
            app.ManualleftcueMenu.Text = 'Manual left cue';
            % Assign app.ContextMenu28
            app.LeftCueButton.ContextMenu = app.ContextMenu28;
            app.ContextMenu29 = uicontextmenu(app.UIFigure);
            app.ManualrightcueMenu = uimenu(app.ContextMenu29);
            app.ManualrightcueMenu.Text = 'Manual right cue';
            % Assign app.ContextMenu29
            app.RightCueButton.ContextMenu = app.ContextMenu29;
            app.StartCueButton.ContextMenu = app.ContextMenu29;
            app.ContextMenu30 = uicontextmenu(app.UIFigure);
            app.ManualleftrewardMenu = uimenu(app.ContextMenu30);
            app.ManualleftrewardMenu.Text = 'Manual left reward';
            % Assign app.ContextMenu30
            app.LeftWaterButton.ContextMenu = app.ContextMenu30;
            app.ContextMenu31 = uicontextmenu(app.UIFigure);
            app.ManualrightrewardMenu = uimenu(app.ContextMenu31);
            app.ManualrightrewardMenu.Text = 'Manual right reward';
            % Assign app.ContextMenu31
            app.RightWaterButton.ContextMenu = app.ContextMenu31;
            app.ContextMenu32 = uicontextmenu(app.UIFigure);
            app.WhenoffwaterandcuewilloperateindependentlyMenu = uimenu(app.ContextMenu32);
            app.WhenoffwaterandcuewilloperateindependentlyMenu.Text = 'When off, water and cue will operate independently';
            app.Menu = uimenu(app.ContextMenu32);
            app.Menu.Text = 'When on, if water button is pushed, cue will also execute. Cue button still functions independently';
            % Assign app.ContextMenu32
            app.CuesinstructedSwitch.ContextMenu = app.ContextMenu32;
            app.TriggercueandmanualwaterSwitch.ContextMenu = app.ContextMenu32;
            app.ContextMenu33 = uicontextmenu(app.UIFigure);
            app.Resetnumberofmanualleftrewardsto0Menu = uimenu(app.ContextMenu33);
            app.Resetnumberofmanualleftrewardsto0Menu.Text = 'Reset number of manual left rewards to 0';
            % Assign app.ContextMenu33
            app.LicksResetButton.ContextMenu = app.ContextMenu33;
            app.ContextMenu35 = uicontextmenu(app.UIFigure);
            app.NumberofmanualleftrewardsMenu = uimenu(app.ContextMenu35);
            app.NumberofmanualleftrewardsMenu.Text = 'Number of manual left rewards';
            % Assign app.ContextMenu35
            app.ManualLeftRewardsLabel.ContextMenu = app.ContextMenu35;
            app.ContextMenu37 = uicontextmenu(app.UIFigure);
            app.Willcause100leftrewardsUsedtocalibrateleftrewardsizeMenu = uimenu(app.ContextMenu37);
            app.Willcause100leftrewardsUsedtocalibrateleftrewardsizeMenu.Text = 'Will cause 100 left rewards. Used to calibrate left reward size';
            % Assign app.ContextMenu37
            app.LCalibrateButton.ContextMenu = app.ContextMenu37;
            app.ContextMenu38 = uicontextmenu(app.UIFigure);
            app.Willcause100rightrewardsUsedtocalibraterightrewardsizeMenu = uimenu(app.ContextMenu38);
            app.Willcause100rightrewardsUsedtocalibraterightrewardsizeMenu.Text = 'Will cause 100 right rewards. Used to calibrate right reward size';
            % Assign app.ContextMenu38
            app.RCalibrateButton.ContextMenu = app.ContextMenu38;
            app.ContextMenu40 = uicontextmenu(app.UIFigure);
            app.Menu2_6 = uimenu(app.ContextMenu40);
            app.Menu2_6.Text = 'Optogenetic stimulation on/off. Other opto parameters do not function if opto is off';
            % Assign app.ContextMenu40
            app.OptogeneticstimulationSwitch.ContextMenu = app.ContextMenu40;
            app.TeststimulationSwitch.ContextMenu = app.ContextMenu40;
            app.ConstantmodulationSwitch.ContextMenu = app.ContextMenu40;
            app.ContextMenu41 = uicontextmenu(app.UIFigure);
            app.ProbabilitythatoptostimulationwilloccurMenu = uimenu(app.ContextMenu41);
            app.ProbabilitythatoptostimulationwilloccurMenu.Text = 'Probability that opto stimulation will occur';
            % Assign app.ContextMenu41
            app.TrialswithstimulationSpinner.ContextMenu = app.ContextMenu41;
            app.PowermodulationSpinner.ContextMenu = app.ContextMenu41;
            app.ContextMenu42 = uicontextmenu(app.UIFigure);
            app.NumberofcorrecttrialsnecessarybeforeoptobeginsMenu = uimenu(app.ContextMenu42);
            app.NumberofcorrecttrialsnecessarybeforeoptobeginsMenu.Text = 'Number of correct trials necessary before opto begins';
            % Assign app.ContextMenu42
            app.CorrecttrialsbeforestimulationEditField.ContextMenu = app.ContextMenu42;
            app.ContextMenu43 = uicontextmenu(app.UIFigure);
            app.TimebetweenoptostarttimeandwhenthelaserisactuallyonMenu = uimenu(app.ContextMenu43);
            app.TimebetweenoptostarttimeandwhenthelaserisactuallyonMenu.Text = 'Time between opto start time and when the laser is actually on';
            % Assign app.ContextMenu43
            app.StimulationdelaymsEditField.ContextMenu = app.ContextMenu43;

            app.ContextMenu44 = uicontextmenu(app.UIFigure);
            app.IfoptoactiveduringITIallowoptostimeverytimeITIisresetMenu = uimenu(app.ContextMenu44);
            app.IfoptoactiveduringITIallowoptostimeverytimeITIisresetMenu.Text = 'If opto active during ITI, allow opto-stim every time ITI re-starts (in the case of an ENL penalty)';
            % Assign app.ContextMenu44
            app.MultiStimITIpenaltyCheckBox.ContextMenu = app.ContextMenu44;
            app.MultiStimCuepenaltyCheckBox.ContextMenu = app.ContextMenu44;
            app.MultiStimDelaypenaltyCheckBox.ContextMenu = app.ContextMenu44;
            app.MultiStimStartCuepenaltyCheckBox.ContextMenu = app.ContextMenu44;
            app.ContextMenu45 = uicontextmenu(app.UIFigure);
            app.AllowmultipleoptostimulationsinablockMenu = uimenu(app.ContextMenu45);
            app.AllowmultipleoptostimulationsinablockMenu.Text = 'Allow multiple opto stimulations in a block';
            % Assign app.ContextMenu45
            app.MultiStimInblockCheckBox.ContextMenu = app.ContextMenu45;
            app.AdddistractionlightCheckBox.ContextMenu = app.ContextMenu45;
            app.ContextMenu46 = uicontextmenu(app.UIFigure);
            app.AllowoptogeneticstimulationtohavecontinuouslaserstimulationMenu = uimenu(app.ContextMenu46);
            app.AllowoptogeneticstimulationtohavecontinuouslaserstimulationMenu.Text = 'Allow optogenetic stimulation to have continuous laser stimulation';
            % Assign app.ContextMenu46
            app.ContinuousstimulationCheckBox.ContextMenu = app.ContextMenu46;
            app.ContextMenu47 = uicontextmenu(app.UIFigure);
            app.DurationthatlaserisonforMenu = uimenu(app.ContextMenu47);
            app.DurationthatlaserisonforMenu.Text = 'Duration that laser is on for';
            % Assign app.ContextMenu47
            app.StimulationdurationmsEditField.ContextMenu = app.ContextMenu47;
            app.TaperstimulationoffmsEditField.ContextMenu = app.ContextMenu47;
            app.ContextMenu48 = uicontextmenu(app.UIFigure);
            app.IfnoncontinuousthedurationofeachlaserpulseMenu = uimenu(app.ContextMenu48);
            app.IfnoncontinuousthedurationofeachlaserpulseMenu.Text = 'If non-continuous, the duration of each laser pulse';
            % Assign app.ContextMenu48
            app.PulsedurationmsEditField.ContextMenu = app.ContextMenu48;
            app.ContextMenu49 = uicontextmenu(app.UIFigure);
            app.IfnoncontinuousfrequencyoflaserMenu = uimenu(app.ContextMenu49);
            app.IfnoncontinuousfrequencyoflaserMenu.Text = 'If non-continuous, frequency of laser';
            % Assign app.ContextMenu49
            app.StimulationfrequencyHzEditField.ContextMenu = app.ContextMenu49;
            app.ContextMenu50 = uicontextmenu(app.UIFigure);
            app.NumberofmanualrightrewardsMenu_2 = uimenu(app.ContextMenu50);
            app.NumberofmanualrightrewardsMenu_2.Text = 'Number of manual right rewards';
            % Assign app.ContextMenu50
            app.ManualRightRewardsLabel.ContextMenu = app.ContextMenu50;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SPOUT2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end